classdef HEAV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HEAPS                      matlab.ui.Figure
        GridLayout3                matlab.ui.container.GridLayout
        FiltersPanel               matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        Label_56                   matlab.ui.control.Label
        Label_55                   matlab.ui.control.Label
        R26                        matlab.ui.control.NumericEditField
        r26                        matlab.ui.control.NumericEditField
        NumberofcomponentsLabel_2  matlab.ui.control.Label
        Label_54                   matlab.ui.control.Label
        Label_53                   matlab.ui.control.Label
        AtomicfractionLabel_2      matlab.ui.control.Label
        r25                        matlab.ui.control.NumericEditField
        R25                        matlab.ui.control.NumericEditField
        Label_60                   matlab.ui.control.Label
        Label_58                   matlab.ui.control.Label
        r24                        matlab.ui.control.NumericEditField
        R24                        matlab.ui.control.NumericEditField
        Label_50                   matlab.ui.control.Label
        Label_49                   matlab.ui.control.Label
        R10                        matlab.ui.control.NumericEditField
        R9                         matlab.ui.control.NumericEditField
        r10                        matlab.ui.control.NumericEditField
        r9                         matlab.ui.control.NumericEditField
        Label_61                   matlab.ui.control.Label
        HkJmolLabel_2              matlab.ui.control.Label
        Label_48                   matlab.ui.control.Label
        Label_47                   matlab.ui.control.Label
        Label_46                   matlab.ui.control.Label
        Label_45                   matlab.ui.control.Label
        rpmLabel                   matlab.ui.control.Label
        eaLabel                    matlab.ui.control.Label
        Label_43                   matlab.ui.control.Label
        Label_42                   matlab.ui.control.Label
        R13                        matlab.ui.control.NumericEditField
        r13                        matlab.ui.control.NumericEditField
        EtaLabel                   matlab.ui.control.Label
        Label_41                   matlab.ui.control.Label
        Label_40                   matlab.ui.control.Label
        R22                        matlab.ui.control.NumericEditField
        r22                        matlab.ui.control.NumericEditField
        r23                        matlab.ui.control.NumericEditField
        r21                        matlab.ui.control.NumericEditField
        r20                        matlab.ui.control.NumericEditField
        r19                        matlab.ui.control.NumericEditField
        R23                        matlab.ui.control.NumericEditField
        R21                        matlab.ui.control.NumericEditField
        R20                        matlab.ui.control.NumericEditField
        R19                        matlab.ui.control.NumericEditField
        Label_38                   matlab.ui.control.Label
        Label_37                   matlab.ui.control.Label
        Label_36                   matlab.ui.control.Label
        Label_35                   matlab.ui.control.Label
        Label_34                   matlab.ui.control.Label
        Label_33                   matlab.ui.control.Label
        Label_32                   matlab.ui.control.Label
        Label_31                   matlab.ui.control.Label
        PSFEatLabel                matlab.ui.control.Label
        kLabel                     matlab.ui.control.Label
        PhiLabel                   matlab.ui.control.Label
        LambdakJmolKLabel          matlab.ui.control.Label
        FMP1DropDown               matlab.ui.control.DropDown
        ImF3DropDown               matlab.ui.control.DropDown
        ImF2DropDown               matlab.ui.control.DropDown
        ImF1DropDown               matlab.ui.control.DropDown
        LSS5DropDown               matlab.ui.control.DropDown
        LSS4DropDown               matlab.ui.control.DropDown
        LSS3DropDown               matlab.ui.control.DropDown
        LSS2DropDown               matlab.ui.control.DropDown
        LSS1DropDown               matlab.ui.control.DropDown
        MC10DropDown               matlab.ui.control.DropDown
        MC8DropDown                matlab.ui.control.DropDown
        MC9DropDown                matlab.ui.control.DropDown
        MC7DropDown                matlab.ui.control.DropDown
        MC6DropDown                matlab.ui.control.DropDown
        MC5DropDown                matlab.ui.control.DropDown
        MC4DropDown                matlab.ui.control.DropDown
        MC3DropDown                matlab.ui.control.DropDown
        MC2DropDown                matlab.ui.control.DropDown
        FMP1CriterionCheckBox      matlab.ui.control.CheckBox
        ImF3CriterionCheckBox      matlab.ui.control.CheckBox
        ImF2CriterionCheckBox      matlab.ui.control.CheckBox
        ImF1CriterionCheckBox      matlab.ui.control.CheckBox
        LSS5CriterionCheckBox      matlab.ui.control.CheckBox
        LSS4CriterionCheckBox      matlab.ui.control.CheckBox
        LSS3CriterionCheckBox      matlab.ui.control.CheckBox
        LSS2CriterionCheckBox      matlab.ui.control.CheckBox
        LSS1CriterionCheckBox      matlab.ui.control.CheckBox
        MC10CriterionCheckBox      matlab.ui.control.CheckBox
        MC8CriterionCheckBox       matlab.ui.control.CheckBox
        MC9CriterionCheckBox       matlab.ui.control.CheckBox
        MC7CriterionCheckBox       matlab.ui.control.CheckBox
        MC1DropDown                matlab.ui.control.DropDown
        MC6CriterionCheckBox       matlab.ui.control.CheckBox
        MC5CriterionCheckBox       matlab.ui.control.CheckBox
        MC4CriterionCheckBox       matlab.ui.control.CheckBox
        MC3CriterionCheckBox       matlab.ui.control.CheckBox
        MC2CriterionCheckBox       matlab.ui.control.CheckBox
        MC1CriterionCheckBox       matlab.ui.control.CheckBox
        R18                        matlab.ui.control.NumericEditField
        Label_30                   matlab.ui.control.Label
        Label_29                   matlab.ui.control.Label
        r18                        matlab.ui.control.NumericEditField
        R17                        matlab.ui.control.NumericEditField
        Label_28                   matlab.ui.control.Label
        Label_27                   matlab.ui.control.Label
        r17                        matlab.ui.control.NumericEditField
        R16                        matlab.ui.control.NumericEditField
        Label_26                   matlab.ui.control.Label
        Label_25                   matlab.ui.control.Label
        r16                        matlab.ui.control.NumericEditField
        R15                        matlab.ui.control.NumericEditField
        Label_24                   matlab.ui.control.Label
        Label_23                   matlab.ui.control.Label
        r15                        matlab.ui.control.NumericEditField
        SJmolKLabel                matlab.ui.control.Label
        Label_57                   matlab.ui.control.Label
        Label_62                   matlab.ui.control.Label
        R1                         matlab.ui.control.NumericEditField
        MeltingpointTmKLabel       matlab.ui.control.Label
        DensitygcmLabel            matlab.ui.control.Label
        VECLabel                   matlab.ui.control.Label
        OmegaLabel                 matlab.ui.control.Label
        rLabel                     matlab.ui.control.Label
        Label_59                   matlab.ui.control.Label
        VECLabel_2                 matlab.ui.control.Label
        GkJmolLabel                matlab.ui.control.Label
        HkJmolLabel                matlab.ui.control.Label
        GammaLabel                 matlab.ui.control.Label
        Label_22                   matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        R14                        matlab.ui.control.NumericEditField
        r14                        matlab.ui.control.NumericEditField
        Label_20                   matlab.ui.control.Label
        Label_19                   matlab.ui.control.Label
        R12                        matlab.ui.control.NumericEditField
        r12                        matlab.ui.control.NumericEditField
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        R11                        matlab.ui.control.NumericEditField
        r11                        matlab.ui.control.NumericEditField
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        R8                         matlab.ui.control.NumericEditField
        r8                         matlab.ui.control.NumericEditField
        R7                         matlab.ui.control.NumericEditField
        r7                         matlab.ui.control.NumericEditField
        R6                         matlab.ui.control.NumericEditField
        r6                         matlab.ui.control.NumericEditField
        R5                         matlab.ui.control.NumericEditField
        r5                         matlab.ui.control.NumericEditField
        R4                         matlab.ui.control.NumericEditField
        r4                         matlab.ui.control.NumericEditField
        R3                         matlab.ui.control.NumericEditField
        r3                         matlab.ui.control.NumericEditField
        R2                         matlab.ui.control.NumericEditField
        r2                         matlab.ui.control.NumericEditField
        r1                         matlab.ui.control.NumericEditField
        xButton                    matlab.ui.control.Button
        Display                    matlab.ui.container.TabGroup
        PropertiesParametersTab    matlab.ui.container.Tab
        PropertiesParametersTable  matlab.ui.control.Table
        CriteriaTab                matlab.ui.container.Tab
        CriteriaTable              matlab.ui.control.Table
        AtomicFractionTab          matlab.ui.container.Tab
        AtomicFractionTable        matlab.ui.control.Table
        WeightFractionTab          matlab.ui.container.Tab
        WeightFractionTable        matlab.ui.control.Table
        PropElementsTab            matlab.ui.container.Tab
        PropElementsTable          matlab.ui.control.Table
        Elements                   matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        Import                     matlab.ui.container.Panel
        AmountDropDown_2           matlab.ui.control.DropDown
        AlloyImportEditField       matlab.ui.control.EditField
        ImportAlloyLabel           matlab.ui.control.Label
        ImportButton               matlab.ui.control.Button
        NButton                    matlab.ui.control.StateButton
        CButton                    matlab.ui.control.StateButton
        BButton                    matlab.ui.control.StateButton
        PButton                    matlab.ui.control.StateButton
        SiButton                   matlab.ui.control.StateButton
        AlButton                   matlab.ui.control.StateButton
        BiButton                   matlab.ui.control.StateButton
        PbButton                   matlab.ui.control.StateButton
        TlButton                   matlab.ui.control.StateButton
        HgButton                   matlab.ui.control.StateButton
        AuButton                   matlab.ui.control.StateButton
        PtButton                   matlab.ui.control.StateButton
        IrButton                   matlab.ui.control.StateButton
        SbButton                   matlab.ui.control.StateButton
        SnButton                   matlab.ui.control.StateButton
        InButton                   matlab.ui.control.StateButton
        CdButton                   matlab.ui.control.StateButton
        AgButton                   matlab.ui.control.StateButton
        PdButton                   matlab.ui.control.StateButton
        RhButton                   matlab.ui.control.StateButton
        LiButton                   matlab.ui.control.StateButton
        NaButton                   matlab.ui.control.StateButton
        KButton                    matlab.ui.control.StateButton
        RbButton                   matlab.ui.control.StateButton
        CsButton                   matlab.ui.control.StateButton
        BeButton                   matlab.ui.control.StateButton
        MgButton                   matlab.ui.control.StateButton
        CaButton                   matlab.ui.control.StateButton
        SrButton                   matlab.ui.control.StateButton
        BaButton                   matlab.ui.control.StateButton
        ScButton                   matlab.ui.control.StateButton
        YButton                    matlab.ui.control.StateButton
        LaButton                   matlab.ui.control.StateButton
        TiButton                   matlab.ui.control.StateButton
        ZrButton                   matlab.ui.control.StateButton
        HfButton                   matlab.ui.control.StateButton
        VButton                    matlab.ui.control.StateButton
        CrButton                   matlab.ui.control.StateButton
        MnButton                   matlab.ui.control.StateButton
        FeButton                   matlab.ui.control.StateButton
        CoButton                   matlab.ui.control.StateButton
        NiButton                   matlab.ui.control.StateButton
        CuButton                   matlab.ui.control.StateButton
        ZnButton                   matlab.ui.control.StateButton
        NbButton                   matlab.ui.control.StateButton
        TaButton                   matlab.ui.control.StateButton
        MoButton                   matlab.ui.control.StateButton
        WButton                    matlab.ui.control.StateButton
        TcButton                   matlab.ui.control.StateButton
        ReButton                   matlab.ui.control.StateButton
        RuButton                   matlab.ui.control.StateButton
        OsButton                   matlab.ui.control.StateButton
        GaButton                   matlab.ui.control.StateButton
        GeButton                   matlab.ui.control.StateButton
        AsButton                   matlab.ui.control.StateButton
        Calculate                  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        TemtStep                   matlab.ui.container.Panel
        ExportButton               matlab.ui.control.Button
        KLabel                     matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
        TemperatureLabel           matlab.ui.control.Label
        RebootButton               matlab.ui.control.Button
        CalculateButton            matlab.ui.control.Button
        FiltersButton              matlab.ui.control.Button
        ApplyrestrictionsCheckBox  matlab.ui.control.CheckBox
        StepsizeEditField          matlab.ui.control.NumericEditField
        StepsizeLabel              matlab.ui.control.Label
        E_10                       matlab.ui.container.Panel
        EToEditField_10            matlab.ui.control.NumericEditField
        EFromEditField_10          matlab.ui.control.NumericEditField
        EFixCheckBox_10            matlab.ui.control.CheckBox
        EEditField_10              matlab.ui.control.NumericEditField
        ELabel_10                  matlab.ui.control.Label
        E_9                        matlab.ui.container.Panel
        EToEditField_9             matlab.ui.control.NumericEditField
        EFromEditField_9           matlab.ui.control.NumericEditField
        EFixCheckBox_9             matlab.ui.control.CheckBox
        EEditField_9               matlab.ui.control.NumericEditField
        ELabel_9                   matlab.ui.control.Label
        E_8                        matlab.ui.container.Panel
        EToEditField_8             matlab.ui.control.NumericEditField
        EFromEditField_8           matlab.ui.control.NumericEditField
        EFixCheckBox_8             matlab.ui.control.CheckBox
        EEditField_8               matlab.ui.control.NumericEditField
        ELabel_8                   matlab.ui.control.Label
        E_7                        matlab.ui.container.Panel
        EToEditField_7             matlab.ui.control.NumericEditField
        EFromEditField_7           matlab.ui.control.NumericEditField
        EFixCheckBox_7             matlab.ui.control.CheckBox
        EEditField_7               matlab.ui.control.NumericEditField
        ELabel_7                   matlab.ui.control.Label
        E_6                        matlab.ui.container.Panel
        EToEditField_6             matlab.ui.control.NumericEditField
        EFromEditField_6           matlab.ui.control.NumericEditField
        EFixCheckBox_6             matlab.ui.control.CheckBox
        EEditField_6               matlab.ui.control.NumericEditField
        ELabel_6                   matlab.ui.control.Label
        E_5                        matlab.ui.container.Panel
        EToEditField_5             matlab.ui.control.NumericEditField
        EFromEditField_5           matlab.ui.control.NumericEditField
        EFixCheckBox_5             matlab.ui.control.CheckBox
        EEditField_5               matlab.ui.control.NumericEditField
        ELabel_5                   matlab.ui.control.Label
        E_4                        matlab.ui.container.Panel
        EToEditField_4             matlab.ui.control.NumericEditField
        EFromEditField_4           matlab.ui.control.NumericEditField
        EFixCheckBox_4             matlab.ui.control.CheckBox
        EEditField_4               matlab.ui.control.NumericEditField
        ELabel_4                   matlab.ui.control.Label
        E_3                        matlab.ui.container.Panel
        EToEditField_3             matlab.ui.control.NumericEditField
        EFromEditField_3           matlab.ui.control.NumericEditField
        EFixCheckBox_3             matlab.ui.control.CheckBox
        EEditField_3               matlab.ui.control.NumericEditField
        ELabel_3                   matlab.ui.control.Label
        E_2                        matlab.ui.container.Panel
        EToEditField_2             matlab.ui.control.NumericEditField
        EFromEditField_2           matlab.ui.control.NumericEditField
        EFixCheckBox_2             matlab.ui.control.CheckBox
        EEditField_2               matlab.ui.control.NumericEditField
        ELabel_2                   matlab.ui.control.Label
        E_1                        matlab.ui.container.Panel
        EToEditField_1             matlab.ui.control.NumericEditField
        EFromEditField_1           matlab.ui.control.NumericEditField
        EFixCheckBox_1             matlab.ui.control.CheckBox
        EEditField_1               matlab.ui.control.NumericEditField
        ELabel_1                   matlab.ui.control.Label
        Panel0                     matlab.ui.container.Panel
        ToLabel                    matlab.ui.control.Label
        FromLabel                  matlab.ui.control.Label
        FixedLabel                 matlab.ui.control.Label
        AmountDropDown             matlab.ui.control.DropDown
        ElementLabel               matlab.ui.control.Label
        OptionCalculate            matlab.ui.container.ButtonGroup
        ExplorerButton             matlab.ui.control.RadioButton
        SinglecalculationButton    matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        Val=1;
        element=[""];
        remove="";
        L=1;
        P=1;
        F=1;
        dis_heas=[""];
        dis_temperature=[""];
        dis_amount_type=[""];
        parameters_1row=["System","T (K)","Tm (K)","At. Weight (g·mol⁻¹)","ρ (g·cm⁻³)","Cp (J·mol⁻¹·K⁻¹)","Thc (W·m⁻¹·K⁻¹)","r (pm)","χᴾ (-)","χᴬ (-)","VEC (-)","e/a (-)","δr (%)","δχᴾ (%)","Δχᴾ (-)","δχᴬ (%)","Δχᴬ (-)","ΔVEC (-)","ΔHᵐ (kJ·mol⁻¹)","ΔSᵐ (J·mol⁻¹·K⁻¹)","ΔGᵐ (kJ·mol⁻¹)","ΔHᶦᶜ (kJ·mol⁻¹)","Sˣˢ (bcc) (J·mol⁻¹·K⁻¹)","Sˣˢ (fcc) (J·mol⁻¹·K⁻¹)","ɣ (-)","Ω (-)","Λ (J·mol⁻¹·K⁻¹)","φ (bcc) (-)","φ (fcc) (-)","η (-)","Δκ (-)","PSFE (%)"];
        dis_parameters=[""];
        criteria_1row=["System","T (K)","MC1 ΔHᵐ-δr","MC2 Ω-δr","MC3 δr-ΔHᵐ","MC4 γ","MC5 δχᴬ-δr","MC6 Λ","MC7 T/Tm-δr-ΔHᵐ","MC8 φ","MC9 η-ΔHᶦᶜ","MC10 Δk","LSS1 VEC","LSS2 VEC-e/a","LSS3 T/Tm-VEC","LSS4 φ-VEC","LSS5 T-VEC-r-Δχᴬ-ΔVEC","ImF1 Δχᴾ","ImF2 PSFE-VEC","ImF3 δχᴬ-δr","FMP1 VEC"];
        dis_criteria=[""];
        fraction_1row=["System",""];
        dis_Afraction=[""];
        dis_Wfraction=[""];
        elements_1row=["Elements",""];
        dis_element=["At. Number (-)";"At. Radius (pm)";"Melting point (K)";"Boiling point (K)";"Pauling electronegativity (-)";"Allen electronegativity (-)";"Valence electron concentration (-)";"Itinerant electrons per atom (-)";"At. Weight (g·mol⁻¹)";"Density (g·cm⁻³)";"Molar heat capacity (J·mol⁻¹·K⁻¹)";"Thermal conductivity (W·m⁻¹·K⁻¹)"];
        
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
    %the function is called when an elementary button is pressed for the first time
        function button(app) 
            for i=1:1:app.Val
                if i==1
                    app.ELabel_1.Text=app.element(i);
                    app.E_1.Visible='on';
                elseif i==2
                    app.ELabel_2.Text=app.element(i);
                    app.E_2.Visible='on';
                elseif i==3
                    app.ELabel_3.Text=app.element(i);
                    app.E_3.Visible='on';
                elseif i==4
                    app.ELabel_4.Text=app.element(i);
                    app.E_4.Visible='on';
                elseif i==5
                    app.ELabel_5.Text=app.element(i);
                    app.E_5.Visible='on';
                elseif i==6
                    app.ELabel_6.Text=app.element(i);
                    app.E_6.Visible='on';
                elseif i==7
                    app.ELabel_7.Text=app.element(i);
                    app.E_7.Visible='on';
                elseif i==8
                    app.ELabel_8.Text=app.element(i);
                    app.E_8.Visible='on';
                elseif i==9
                    app.ELabel_9.Text=app.element(i);
                    app.E_9.Visible='on';
                elseif i==10
                    app.ELabel_10.Text=app.element(i);
                    app.E_10.Visible='on';
                else
                    return
                end
            end
            app.Val=app.Val+1;
        end
        
        %the function is called when an elementary button is pressed for the second time
        function xbutton(app) 
            for i=1:1:app.Val
                if app.element(i)==app.remove
                    app.element(i)=[];
                    app.Val=app.Val-1;
                    for j=0:1:length(app.element)
                        if j==1
                            app.ELabel_1.Text=app.element(j);
                        elseif j==2
                            app.ELabel_2.Text=app.element(j);
                        elseif j==3
                            app.ELabel_3.Text=app.element(j);
                        elseif j==4
                            app.ELabel_4.Text=app.element(j);
                        elseif j==5
                            app.ELabel_5.Text=app.element(j);
                        elseif j==6
                            app.ELabel_6.Text=app.element(j);
                        elseif j==7
                            app.ELabel_7.Text=app.element(j);
                        elseif j==8
                            app.ELabel_8.Text=app.element(j);
                        elseif j==9
                            app.ELabel_9.Text=app.element(j);
                        elseif j==10
                            app.ELabel_10.Text=app.element(j);
                        end
                    end
                    break
                end
            end
            
            if app.Val==1
                app.E_1.Visible='off';
            elseif app.Val==2
                app.E_2.Visible='off';
            elseif app.Val==3
                app.E_3.Visible='off';
            elseif app.Val==4
                app.E_4.Visible='off';
            elseif app.Val==5
                app.E_5.Visible='off';
            elseif app.Val==6
                app.E_6.Visible='off';
            elseif app.Val==7
                app.E_7.Visible='off';
            elseif app.Val==8
                app.E_8.Visible='off';
            elseif app.Val==9
                app.E_9.Visible='off';
            elseif app.Val==10
                app.E_10.Visible='off';
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RebootButton
        function RebootButtonButtonPushed(app, event)
            app.L=1;
            app.P=1;
            app.F=1;
            app.dis_heas=[""];
            app.dis_temperature=[""];
            app.dis_amount_type=[""];
            app.dis_parameters=[""];
            app.dis_criteria=[""];
            app.fraction_1row=["System",""];
            app.dis_Afraction=[""];
            app.dis_Wfraction=[""];
            app.elements_1row=["Elements",""];
            app.dis_element=["At. Number (-)";"At. Radius (pm)";"Melting point (K)";"Boiling point (K)";"Pauling electronegativity (-)";"Allen electronegativity (-)";"Valence electron concentration (-)";"Itinerant electrons per atom (-)";"At. Weight (g·mol⁻¹)";"Density (g·cm⁻³)";"Molar heat capacity (J·mol⁻¹·K⁻¹)";"Thermal conductivity (W·m⁻¹·K⁻¹)"];
        
            app.PropertiesParametersTable.ColumnName=app.parameters_1row;
            app.PropertiesParametersTable.Data=char.empty;
            app.CriteriaTable.ColumnName=app.criteria_1row;
            app.CriteriaTable.Data=char.empty;
            app.AtomicFractionTable.ColumnName=["System","Components"];
            app.AtomicFractionTable.Data=char.empty;
            app.WeightFractionTable.ColumnName=["System","Components"];
            app.WeightFractionTable.Data=char.empty;
            app.PropElementsTable.ColumnName=["Properties","Components"];
            app.PropElementsTable.Data=char.empty;
            
            app.RebootButton.Enable='off';
            app.ExportButton.Enable='off';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            oldpath=cd;
            [filename,newpath] = uiputfile('*.xls');
            if newpath==0
                return
            end
            cd(newpath);

            writematrix([app.PropertiesParametersTable.ColumnName.';app.PropertiesParametersTable.Data],filename,'sheet','Properties & Parameters');
            writematrix([app.CriteriaTable.ColumnName.';app.CriteriaTable.Data],filename,'sheet','Criteria');
            writematrix([app.AtomicFractionTable.ColumnName.';app.AtomicFractionTable.Data],filename,'sheet','Atomic Fraction');
            writematrix([app.WeightFractionTable.ColumnName.';app.WeightFractionTable.Data],filename,'sheet','Weight Fraction');
            writematrix([app.PropElementsTable.ColumnName.';app.PropElementsTable.Data],filename,'sheet','Element Properties');
            
            cd(oldpath);
            
        end

        % Selection changed function: OptionCalculate
        function OptionCalculateSelectionChanged(app, event)
            selectedButton = app.OptionCalculate.SelectedObject;
            text=selectedButton.Text;
            if text=="Explorer"
                if app.AmountDropDown.Value=="weight percent" || app.AmountDropDown.Value=="atomic percent"
                    app.EFixCheckBox_1.Enable='off';
                    app.EFixCheckBox_1.Value=false;
                else
                    app.EFixCheckBox_1.Enable='on';
                    app.EFixCheckBox_1.Value=true;
                end
                app.EFixCheckBox_2.Enable='on';
                app.EFixCheckBox_3.Enable='on';
                app.EFixCheckBox_4.Enable='on';
                app.EFixCheckBox_5.Enable='on';
                app.EFixCheckBox_6.Enable='on';
                app.EFixCheckBox_7.Enable='on';
                app.EFixCheckBox_8.Enable='on';
                app.EFixCheckBox_9.Enable='on';
                app.EFixCheckBox_10.Enable='on';
                app.StepsizeLabel.Enable='on';
                app.StepsizeEditField.Enable='on';
                app.ApplyrestrictionsCheckBox.Enable='on';
            else
                app.EFixCheckBox_1.Enable='off';
                app.EFixCheckBox_1.Value=true;
                app.EFromEditField_1.Enable='off';
                app.EToEditField_1.Enable='off';
                app.EFixCheckBox_2.Enable='off';
                app.EFixCheckBox_2.Value=true;
                app.EFromEditField_2.Enable='off';
                app.EToEditField_2.Enable='off';
                app.EFixCheckBox_3.Enable='off';
                app.EFixCheckBox_3.Value=true;
                app.EFromEditField_3.Enable='off';
                app.EToEditField_3.Enable='off';
                app.EFixCheckBox_4.Enable='off';
                app.EFixCheckBox_4.Value=true;
                app.EFromEditField_4.Enable='off';
                app.EToEditField_4.Enable='off';
                app.EFixCheckBox_5.Enable='off';
                app.EFixCheckBox_5.Value=true;
                app.EFromEditField_5.Enable='off';
                app.EToEditField_5.Enable='off';
                app.EFixCheckBox_6.Enable='off';
                app.EFixCheckBox_6.Value=true;
                app.EFromEditField_6.Enable='off';
                app.EToEditField_6.Enable='off';
                app.EFixCheckBox_7.Enable='off';
                app.EFixCheckBox_7.Value=true;
                app.EFromEditField_7.Enable='off';
                app.EToEditField_7.Enable='off';
                app.EFixCheckBox_8.Enable='off';
                app.EFixCheckBox_8.Value=true;
                app.EFromEditField_8.Enable='off';
                app.EToEditField_8.Enable='off';
                app.EFixCheckBox_9.Enable='off';
                app.EFixCheckBox_9.Value=true;
                app.EFromEditField_9.Enable='off';
                app.EToEditField_9.Enable='off';
                app.EFixCheckBox_10.Enable='off';
                app.EFixCheckBox_10.Value=true;
                app.EFromEditField_10.Enable='off';
                app.EToEditField_10.Enable='off';
                app.StepsizeLabel.Enable='off';
                app.StepsizeEditField.Enable='off';
                app.ApplyrestrictionsCheckBox.Enable='off';
                app.ApplyrestrictionsCheckBox.Value=false;
                app.FiltersButton.Enable='off';
            end
        end

        % Value changed function: AmountDropDown
        function AmountDropDownValueChanged(app, event)
            value = app.AmountDropDown.Value;
            if value=="Quantity Type"
            elseif value=="Molar ratio"
                app.EEditField_1.Enable='on';
                
                selectedButton = app.OptionCalculate.SelectedObject;
                text=selectedButton.Text;
                if text=="Explorer"
                    app.EFixCheckBox_1.Enable='on';
                    app.EFixCheckBox_1.Value=true;
                end
                app.EToEditField_1.Value=5;
                app.EFromEditField_1.Value=0;
                app.EToEditField_2.Value=5;
                app.EFromEditField_2.Value=0;
                app.EToEditField_3.Value=5;
                app.EFromEditField_3.Value=0;
                app.EToEditField_4.Value=5;
                app.EFromEditField_4.Value=0;
                app.EToEditField_5.Value=5;
                app.EFromEditField_5.Value=0;
                app.EToEditField_6.Value=5;
                app.EFromEditField_6.Value=0;
                app.EToEditField_7.Value=5;
                app.EFromEditField_7.Value=0;
                app.EToEditField_8.Value=5;
                app.EFromEditField_8.Value=0;
                app.EToEditField_9.Value=5;
                app.EFromEditField_9.Value=0;
                app.EToEditField_10.Value=5;
                app.EFromEditField_10.Value=0;
                app.StepsizeEditField.Value=1;
                
                app.EEditField_1.Value=1;
                app.EEditField_2.Value=1;
                app.EEditField_3.Value=1;
                app.EEditField_4.Value=1;
                app.EEditField_5.Value=1;
                app.EEditField_6.Value=1;
                app.EEditField_7.Value=1;
                app.EEditField_8.Value=1;
                app.EEditField_9.Value=1;
                app.EEditField_10.Value=1;
            else
                selectedButton = app.OptionCalculate.SelectedObject;
                text=selectedButton.Text;
                if text=="Explorer"
                    app.EFixCheckBox_1.Enable='off';
                    app.EFixCheckBox_1.Value=false;
                end
                app.EEditField_1.Enable='off';
                app.EFixCheckBox_1.Enable='off';
                app.EToEditField_1.Enable='off';
                app.EFromEditField_1.Enable='off';
                app.EToEditField_1.Value=100;
                app.EFromEditField_1.Value=0;
                app.EToEditField_2.Value=0;
                app.EFromEditField_2.Value=0;
                app.EToEditField_3.Value=0;
                app.EFromEditField_3.Value=0;
                app.EToEditField_4.Value=0;
                app.EFromEditField_4.Value=0;
                app.EToEditField_5.Value=0;
                app.EFromEditField_5.Value=0;
                app.EToEditField_6.Value=0;
                app.EFromEditField_6.Value=0;
                app.EToEditField_7.Value=0;
                app.EFromEditField_7.Value=0;
                app.EToEditField_8.Value=0;
                app.EFromEditField_8.Value=0;
                app.EToEditField_9.Value=0;
                app.EFromEditField_9.Value=0;
                app.EToEditField_10.Value=0;
                app.EFromEditField_10.Value=0;
                app.StepsizeEditField.Value=10;
                
                app.EEditField_1.Value=100;
                app.EEditField_2.Value=0;
                app.EEditField_3.Value=0;
                app.EEditField_4.Value=0;
                app.EEditField_5.Value=0;
                app.EEditField_6.Value=0;
                app.EEditField_7.Value=0;
                app.EEditField_8.Value=0;
                app.EEditField_9.Value=0;
                app.EEditField_10.Value=0;
                
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            amount_fix=[app.EEditField_1.Value,app.EEditField_2.Value,app.EEditField_3.Value,app.EEditField_4.Value,app.EEditField_5.Value,app.EEditField_6.Value,app.EEditField_7.Value,app.EEditField_8.Value,app.EEditField_9.Value,app.EEditField_10.Value];
            amount_type=app.AmountDropDown.Value;
            fix=[app.EFixCheckBox_1.Value,app.EFixCheckBox_2.Value,app.EFixCheckBox_3.Value,app.EFixCheckBox_4.Value,app.EFixCheckBox_5.Value,app.EFixCheckBox_6.Value,app.EFixCheckBox_7.Value,app.EFixCheckBox_8.Value,app.EFixCheckBox_9.Value,app.EFixCheckBox_10.Value];
            amount_to=[app.EToEditField_1.Value,app.EToEditField_2.Value,app.EToEditField_3.Value,app.EToEditField_4.Value,app.EToEditField_5.Value,app.EToEditField_6.Value,app.EToEditField_7.Value,app.EToEditField_8.Value,app.EToEditField_9.Value,app.EToEditField_10.Value];
            amount_from=[app.EFromEditField_1.Value,app.EFromEditField_2.Value,app.EFromEditField_3.Value,app.EFromEditField_4.Value,app.EFromEditField_5.Value,app.EFromEditField_6.Value,app.EFromEditField_7.Value,app.EFromEditField_8.Value,app.EFromEditField_9.Value,app.EFromEditField_10.Value];
            filters=app.ApplyrestrictionsCheckBox.Value;
            stepsize=app.StepsizeEditField.Value;
            Tinput=app.TemperatureEditField.Value;
            Prst=[app.r1.Value,app.r2.Value,app.r3.Value,app.r4.Value,app.r5.Value,app.r6.Value,app.r7.Value,app.r8.Value,app.r9.Value,app.r10.Value,app.r11.Value,app.r12.Value,app.r13.Value,app.r14.Value,app.r15.Value,app.r16.Value,app.r17.Value,app.r18.Value,app.r19.Value,app.r20.Value,app.r21.Value,app.r21.Value,app.r22.Value,app.r23.Value,app.r24.Value];
            PRst=[app.R1.Value,app.R2.Value,app.R3.Value,app.R4.Value,app.R5.Value,app.R6.Value,app.R7.Value,app.R8.Value,app.R9.Value,app.R10.Value,app.R11.Value,app.R12.Value,app.R13.Value,app.R14.Value,app.R15.Value,app.R16.Value,app.R17.Value,app.R18.Value,app.R19.Value,app.R20.Value,app.R21.Value,app.R21.Value,app.R22.Value,app.R23.Value,app.R24.Value];
            Ppstate=[app.R1.Enable,app.R2.Enable,app.R3.Enable,app.R4.Enable,app.R5.Enable,app.R6.Enable,app.R7.Enable,app.R8.Enable,app.R9.Enable,app.R10.Enable,app.R11.Enable,app.R12.Enable,app.R13.Enable,app.R14.Enable,app.R15.Enable,app.R16.Enable,app.R17.Enable,app.R18.Enable,app.R19.Enable,app.R20.Enable,app.R21.Enable,app.R21.Enable,app.R22.Enable,app.R23.Enable,app.R24.Enable];
            at_fracc=[app.r25.Value,app.R25.Value];
            n_comp=[app.r26.Value,app.R26.Value];
            Crst={app.MC1DropDown.Value,app.MC2DropDown.Value,app.MC3DropDown.Value,app.MC4DropDown.Value,app.MC5DropDown.Value,app.MC6DropDown.Value,app.MC7DropDown.Value,app.MC8DropDown.Value,app.MC9DropDown.Value,app.MC10DropDown.Value,app.LSS1DropDown.Value,app.LSS2DropDown.Value,app.LSS3DropDown.Value,app.LSS4DropDown.Value,app.LSS5DropDown.Value,app.ImF1DropDown.Value,app.ImF2DropDown.Value,app.ImF3DropDown.Value,app.FMP1DropDown.Value};
            
            n_element=length(app.element);
           
            [limit]=input_validator(n_element,amount_fix,amount_type,fix,amount_from,stepsize,amount_to,filters,Prst,PRst,at_fracc,n_comp);
            
            if n_element<3
                return;
            end
            if strlength(limit(1))~=0
                return;
            end
            
            %Call amount_variator function
            amount_explorer=amount_variator(n_element,amount_fix,amount_type,fix,amount_from,stepsize,amount_to);
            
            %Call alloy_calculate function
            [heas,m_parameters,m_criteria,m_Afraction,m_Wfraction,properties]=alloy_calculate(app.element,n_element,amount_explorer,amount_type,filters,Prst,PRst,Crst,at_fracc,n_comp,Tinput,Ppstate);
            selectedButton = app.OptionCalculate.SelectedObject;
            text=selectedButton.Text;
            
            if isempty(heas)
                return;
            elseif text=="Explorer"
                
                app.PropertiesParametersTable.ColumnName=app.parameters_1row(1,[1 3:end]);
                app.PropertiesParametersTable.Data=[heas(:,1),m_parameters(:,2:end)];
                app.CriteriaTable.ColumnName=app.criteria_1row([1 3:end]);
                app.CriteriaTable.Data=[heas(:,1),m_criteria(:,2:end)];%modificar
                app.AtomicFractionTable.ColumnName=["System", app.element];
                app.AtomicFractionTable.Data=[heas(:,1),m_Afraction];
                app.WeightFractionTable.ColumnName=["System", app.element];
                app.WeightFractionTable.Data=[heas(:,1),m_Wfraction];
                app.PropElementsTable.ColumnName=["Elements",app.element];
                app.PropElementsTable.Data=[app.dis_element(:,1),properties];
            
            else
                f=1;
                for i=1:1:length(app.dis_heas(:,1))
                    if app.dis_heas(i,1)==heas
                        if app.dis_amount_type(i,1)==amount_type
                            if app.dis_temperature(i,1)==num2str(Tinput)
                                return;
                            end
                        end
                        f=0;
                    end
                end
                app.dis_heas(app.L,1)=heas;
                app.dis_temperature(app.L,1)=num2str(Tinput);
                app.dis_amount_type(app.L,1)=amount_type;
                for i=1:1:n_element
                    if app.element(i)~=app.elements_1row %comprueba si elemento no esta repetido
                        app.P=app.P+1;
                        app.elements_1row(app.P)=app.element(i);
                        app.fraction_1row(app.P)=app.element(i);
                        app.dis_element(:,app.P)=properties(:,i);                       
                    end
                end
                
                if f==1
                    for i=1:1:n_element
                        for j=2:1:app.P
                            if app.element(i)==app.fraction_1row(j)
                                app.dis_Afraction(app.F,j)=num2str(m_Afraction(i));
                                app.dis_Wfraction(app.F,j)=num2str(m_Wfraction(i));
                            end
                        end
                    end
                    app.dis_Afraction(app.F,1)=app.dis_heas(app.L,1);
                    app.dis_Wfraction(app.F,1)=app.dis_heas(app.L,1);
                    app.F=app.F+1;
                end

                for i=1:1:length(m_parameters)
                    j=i+1;
                    app.dis_parameters(app.L,j)=num2str(m_parameters(i));
                end
                app.dis_parameters(app.L,1)=app.dis_heas(app.L);
                    
                for i=1:1:length(m_criteria)
                    j=i+1;
                    app.dis_criteria(app.L,j)=m_criteria(i);
                end
                app.dis_criteria(app.L,1)=app.dis_heas(app.L);

                app.L=app.L+1;
                
                app.PropertiesParametersTable.ColumnName=app.parameters_1row;
                app.PropertiesParametersTable.Data=app.dis_parameters;
                app.CriteriaTable.ColumnName=app.criteria_1row;
                app.CriteriaTable.Data=app.dis_criteria;
                app.AtomicFractionTable.ColumnName=app.fraction_1row;
                app.AtomicFractionTable.Data=app.dis_Afraction;
                app.WeightFractionTable.ColumnName=app.fraction_1row;
                app.WeightFractionTable.Data=app.dis_Wfraction;
                app.PropElementsTable.ColumnName=app.elements_1row;
                app.PropElementsTable.Data=app.dis_element;
                
            end

           
            app.RebootButton.Enable='on';
            app.ExportButton.Enable='on';
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.ExportButton.Enable='off';
            
            amount_type=app.AmountDropDown_2.Value;
            
            if amount_type=="Quantity Type"
                msgbox("-You must choose a quantity type", 'Exception!','error');
                return
            end
            
            oldpath=cd;
            [filename,newpath] = uigetfile('*.*');
            if newpath==0
                return
            end
            app.AlloyImportEditField.Value=filename;
            cd(newpath);
            
            [num,text,import]=xlsread(filename);
            
            cd(oldpath);
            %filename=strcat(,text);
            %[num,text,import]=xlsread(filename);
            
            Tfix=app.TemperatureEditField.Value;
            
            
            [heas,m_parameters,m_criteria,m_element,m_Afraction,m_Wfraction,m_properties]=import_function(import,Tfix,amount_type);
            
            m_parameters=[heas(:,1),m_parameters(:,:)];
            m_criteria=[heas(:,1),m_criteria(:,:)];
            m_fraction=["System",m_element(:,:)];
            m_Afraction=[heas(:,1),m_Afraction(:,:)];
            m_Wfraction=[heas(:,1),m_Wfraction(:,:)];
            m_element=["Elements",m_element(:,:)];
            m_properties=[app.dis_element(:,1),m_properties(:,:)];
            
            app.PropertiesParametersTable.ColumnName=app.parameters_1row;
            app.PropertiesParametersTable.Data=m_parameters;
            app.CriteriaTable.ColumnName=app.criteria_1row;
            app.CriteriaTable.Data=m_criteria;
            app.AtomicFractionTable.ColumnName=m_fraction;
            app.AtomicFractionTable.Data=m_Afraction;
            app.WeightFractionTable.ColumnName=m_fraction;
            app.WeightFractionTable.Data=m_Wfraction;
            app.PropElementsTable.ColumnName=m_element;
            app.PropElementsTable.Data=m_properties;
            
            cd(newpath);
            writematrix([app.parameters_1row;m_parameters],filename,'sheet','Properties & Parameters');
            writematrix([app.criteria_1row;m_criteria],filename,'sheet','Criteria');
            writematrix([m_fraction;m_Afraction],filename,'sheet','Atomic Fraction');
            writematrix([m_fraction;m_Wfraction],filename,'sheet','Weight Fraction');
            writematrix([m_element;m_properties],filename,'sheet','Element Properties');
            cd(oldpath);
            
            app.RebootButton.Enable='on';
            
        end

        % Value changed function: EFixCheckBox_1
        function EFixCheckBox_1ValueChanged(app, event)
            value = app.EFixCheckBox_1.Value;
            if value
                app.EFromEditField_1.Enable='off';
                app.EToEditField_1.Enable='off';
            else
                app.EFromEditField_1.Enable='on';
                app.EToEditField_1.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_2
        function EFixCheckBox_2ValueChanged(app, event)
            value = app.EFixCheckBox_2.Value;
            if value
                app.EFromEditField_2.Enable='off';
                app.EToEditField_2.Enable='off';
            else
                app.EFromEditField_2.Enable='on';
                app.EToEditField_2.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_3
        function EFixCheckBox_3ValueChanged(app, event)
            value = app.EFixCheckBox_3.Value;
            if value
                app.EFromEditField_3.Enable='off';
                app.EToEditField_3.Enable='off';
            else
                app.EFromEditField_3.Enable='on';
                app.EToEditField_3.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_4
        function EFixCheckBox_4ValueChanged(app, event)
            value = app.EFixCheckBox_4.Value;
            if value
                app.EFromEditField_4.Enable='off';
                app.EToEditField_4.Enable='off';
            else
                app.EFromEditField_4.Enable='on';
                app.EToEditField_4.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_5
        function EFixCheckBox_5ValueChanged(app, event)
            value = app.EFixCheckBox_5.Value;
            if value
                app.EFromEditField_5.Enable='off';
                app.EToEditField_5.Enable='off';
            else
                app.EFromEditField_5.Enable='on';
                app.EToEditField_5.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_6
        function EFixCheckBox_6ValueChanged(app, event)
            value = app.EFixCheckBox_6.Value;
            if value
                app.EFromEditField_6.Enable='off';
                app.EToEditField_6.Enable='off';
            else
                app.EFromEditField_6.Enable='on';
                app.EToEditField_6.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_7
        function EFixCheckBox_7ValueChanged(app, event)
            value = app.EFixCheckBox_7.Value;
            if value
                app.EFromEditField_7.Enable='off';
                app.EToEditField_7.Enable='off';
            else
                app.EFromEditField_7.Enable='on';
                app.EToEditField_7.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_8
        function EFixCheckBox_8ValueChanged(app, event)
            value = app.EFixCheckBox_8.Value;
            if value
                app.EFromEditField_8.Enable='off';
                app.EToEditField_8.Enable='off';
            else
                app.EFromEditField_8.Enable='on';
                app.EToEditField_8.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_9
        function EFixCheckBox_9ValueChanged(app, event)
            value = app.EFixCheckBox_9.Value;
            if value
                app.EFromEditField_9.Enable='off';
                app.EToEditField_9.Enable='off';
            else
                app.EFromEditField_9.Enable='on';
                app.EToEditField_9.Enable='on';
            end
        end

        % Value changed function: EFixCheckBox_10
        function EFixCheckBox_10ValueChanged(app, event)
            value = app.EFixCheckBox_10.Value;
            if value
                app.EFromEditField_10.Enable='off';
                app.EToEditField_10.Enable='off';
            else
                app.EFromEditField_10.Enable='on';
                app.EToEditField_10.Enable='on';
            end
        end

        % Value changed function: ApplyrestrictionsCheckBox
        function ApplyrestrictionsCheckBoxValueChanged(app, event)
            value = app.ApplyrestrictionsCheckBox.Value;
            if value
                app.FiltersButton.Enable='on';
            else
                app.FiltersButton.Enable='off';
            end
        end

        % Button pushed function: FiltersButton
        function FiltersButtonPushed(app, event)
            app.FiltersPanel.Visible='on';
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.FiltersPanel.Visible='off';
        end

        % Callback function
        function ExporttoExcelCheckBoxValueChanged(app, event)
            value = app.ExporttoExcelCheckBox.Value;
            if value
                app.FilenameEditField.Enable='on';
            else
                app.FilenameEditField.Enable='off';
            end
        end

        % Value changed function: LiButton
        function LiButtonValueChanged(app, event)
            value = app.LiButton.Value;
            
            if value
                if app.Val==11
                    app.LiButton.Value=false;
                    return
                end
                app.element(app.Val)="Li";
                app.button
            else
                app.remove="Li";
                app.xbutton
            end
        end

        % Value changed function: NaButton
        function NaButtonValueChanged(app, event)
            value = app.NaButton.Value;
            if value
                if app.Val==11
                    app.NaButton.Value=false;
                    return
                end
                app.element(app.Val)="Na";
                app.button
            else
                app.remove="Na";
                app.xbutton
            end
        end

        % Value changed function: NButton
        function NButtonValueChanged(app, event)
            value = app.NButton.Value;
            if value
                if app.Val==11
                    app.NButton.Value=false;
                    return
                end
                app.element(app.Val)="N";
                app.button
            else
                app.remove="N";
                app.xbutton
            end
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            value = app.CButton.Value;
            if value
                if app.Val==11
                    app.CButton.Value=false;
                    return
                end
                app.element(app.Val)="C";
                app.button
            else
                app.remove="C";
                app.xbutton
            end
        end

        % Value changed function: BButton
        function BButtonValueChanged(app, event)
            value = app.BButton.Value;
            if value
                if app.Val==11
                    app.BButton.Value=false;
                    return
                end
                app.element(app.Val)="B";
                app.button
            else
                app.remove="B";
                app.xbutton
            end
        end

        % Value changed function: PButton
        function PButtonValueChanged(app, event)
            value = app.PButton.Value;
            if value
                if app.Val==11
                    app.PButton.Value=false;
                    return
                end
                app.element(app.Val)="P";
                app.button
            else
                app.remove="P";
                app.xbutton
            end
        end

        % Value changed function: SiButton
        function SiButtonValueChanged(app, event)
            value = app.SiButton.Value;
            if value
                if app.Val==11
                    app.SiButton.Value=false;
                    return
                end
                app.element(app.Val)="Si";
                app.button
            else
                app.remove="Si";
                app.xbutton
            end
        end

        % Value changed function: AlButton
        function AlButtonValueChanged(app, event)
            value = app.AlButton.Value;
            if value
                if app.Val==11
                    app.AlButton.Value=false;
                    return
                end
                app.element(app.Val)="Al";
                app.button
            else
                app.remove="Al";
                app.xbutton
            end
        end

        % Value changed function: BiButton
        function BiButtonValueChanged(app, event)
            value = app.BiButton.Value;
            if value
                if app.Val==11
                    app.BiButton.Value=false;
                    return
                end
                app.element(app.Val)="Bi";
                app.button
            else
                app.remove="Bi";
                app.xbutton
            end
        end

        % Value changed function: PbButton
        function PbButtonValueChanged(app, event)
            value = app.PbButton.Value;
            if value
                if app.Val==11
                    app.PbButton.Value=false;
                    return
                end
                app.element(app.Val)="Pb";
                app.button
            else
                app.remove="Pb";
                app.xbutton
            end
        end

        % Value changed function: TlButton
        function TlButtonValueChanged(app, event)
            value = app.TlButton.Value;
            if value
                if app.Val==11
                    app.TlButton.Value=false;
                    return
                end
                app.element(app.Val)="Ti";
                app.button
            else
                app.remove="Ti";
                app.xbutton
            end
        end

        % Value changed function: HgButton
        function HgButtonValueChanged(app, event)
            value = app.HgButton.Value;
            if value
                if app.Val==11
                    app.HgButton.Value=false;
                    return
                end
                app.element(app.Val)="Hg";
                app.button
            else
                app.remove="Hg";
                app.xbutton
            end
        end

        % Value changed function: AuButton
        function AuButtonValueChanged(app, event)
            value = app.AuButton.Value;
            if value
                if app.Val==11
                    app.AuButton.Value=false;
                    return
                end
                app.element(app.Val)="Au";
                app.button
            else
                app.remove="Au";
                app.xbutton
            end
        end

        % Value changed function: PtButton
        function PtButtonValueChanged(app, event)
            value = app.PtButton.Value;
            if value
                if app.Val==11
                    app.PtButton.Value=false;
                    return
                end
                app.element(app.Val)="Pt";
                app.button
            else
                app.remove="Pt";
                app.xbutton
            end
        end

        % Value changed function: IrButton
        function IrButtonValueChanged(app, event)
            value = app.IrButton.Value;
            if value
                if app.Val==11
                    app.IrButton.Value=false;
                    return
                end
                app.element(app.Val)="Ir";
                app.button
            else
                app.remove="Ir";
                app.xbutton
            end
        end

        % Value changed function: SbButton
        function SbButtonValueChanged(app, event)
            value = app.SbButton.Value;
            if value
                if app.Val==11
                    app.SbButton.Value=false;
                    return
                end
                app.element(app.Val)="Sb";
                app.button
            else
                app.remove="Sb";
                app.xbutton
            end
        end

        % Value changed function: SnButton
        function SnButtonValueChanged(app, event)
            value = app.SnButton.Value;
            if value
                if app.Val==11
                    app.SnButton.Value=false;
                    return
                end
                app.element(app.Val)="Sn";
                app.button
            else
                app.remove="Sn";
                app.xbutton
            end
        end

        % Value changed function: InButton
        function InButtonValueChanged(app, event)
            value = app.InButton.Value;
            if value
                if app.Val==11
                    app.InButton.Value=false;
                    return
                end
                app.element(app.Val)="In";
                app.button
            else
                app.remove="In";
                app.xbutton
            end
        end

        % Value changed function: CdButton
        function CdButtonValueChanged(app, event)
            value = app.CdButton.Value;
            if value
                if app.Val==11
                    app.CdButton.Value=false;
                    return
                end
                app.element(app.Val)="Cd";
                app.button
            else
                app.remove="Cd";
                app.xbutton
            end
        end

        % Value changed function: AgButton
        function AgButtonValueChanged(app, event)
            value = app.AgButton.Value;
            if value
                if app.Val==11
                    app.AgButton.Value=false;
                    return
                end
                app.element(app.Val)="Ag";
                app.button
            else
                app.remove="Ag";
                app.xbutton
            end
        end

        % Value changed function: PdButton
        function PdButtonValueChanged(app, event)
            value = app.PdButton.Value;
            if value
                if app.Val==11
                    app.PdButton.Value=false;
                    return
                end
                app.element(app.Val)="Pd";
                app.button
            else
                app.remove="Pd";
                app.xbutton
            end
        end

        % Value changed function: RhButton
        function RhButtonValueChanged(app, event)
            value = app.RhButton.Value;
            if value
                if app.Val==11
                    app.RhButton.Value=false;
                    return
                end
                app.element(app.Val)="Rh";
                app.button
            else
                app.remove="Rh";
                app.xbutton
            end
        end

        % Value changed function: KButton
        function KButtonValueChanged(app, event)
            value = app.KButton.Value;
            if value
                if app.Val==11
                    app.KButton.Value=false;
                    return
                end
                app.element(app.Val)="K";
                app.button
            else
                app.remove="K";
                app.xbutton
            end
        end

        % Value changed function: RbButton
        function RbButtonValueChanged(app, event)
            value = app.RbButton.Value;
            if value
                if app.Val==11
                    app.RbButton.Value=false;
                    return
                end
                app.element(app.Val)="Rb";
                app.button
            else
                app.remove="Rb";
                app.xbutton
            end
        end

        % Value changed function: CsButton
        function CsButtonValueChanged(app, event)
            value = app.CsButton.Value;
            if value
                if app.Val==11
                    app.CsButton.Value=false;
                    return
                end
                app.element(app.Val)="Cs";
                app.button
            else
                app.remove="Cs";
                app.xbutton
            end
        end

        % Value changed function: BeButton
        function BeButtonValueChanged(app, event)
            value = app.BeButton.Value;
            if value
                if app.Val==11
                    app.BeButton.Value=false;
                    return
                end
                app.element(app.Val)="Be";
                app.button
            else
                app.remove="Be";
                app.xbutton
            end
        end

        % Value changed function: MgButton
        function MgButtonValueChanged(app, event)
            value = app.MgButton.Value;
            if value
                if app.Val==11
                    app.MgButton.Value=false;
                    return
                end
                app.element(app.Val)="Mg";
                app.button
            else
                app.remove="Mg";
                app.xbutton
            end
        end

        % Value changed function: CaButton
        function CaButtonValueChanged(app, event)
            value = app.CaButton.Value;
            if value
                if app.Val==11
                    app.CaButton.Value=false;
                    return
                end
                app.element(app.Val)="Ca";
                app.button
            else
                app.remove="Ca";
                app.xbutton
            end
        end

        % Value changed function: SrButton
        function SrButtonValueChanged(app, event)
            value = app.SrButton.Value;
            if value
                if app.Val==11
                    app.SrButton.Value=false;
                    return
                end
                app.element(app.Val)="Sr";
                app.button
            else
                app.remove="Sr";
                app.xbutton
            end
        end

        % Value changed function: BaButton
        function BaButtonValueChanged(app, event)
            value = app.BaButton.Value;
            if value
                if app.Val==11
                    app.BaButton.Value=false;
                    return
                end
                app.element(app.Val)="Ba";
                app.button
            else
                app.remove="Ba";
                app.xbutton
            end
        end

        % Value changed function: ScButton
        function ScButtonValueChanged(app, event)
            value = app.ScButton.Value;
            if value
                if app.Val==11
                    app.ScButton.Value=false;
                    return
                end
                app.element(app.Val)="Sc";
                app.button
            else
                app.remove="Sc";
                app.xbutton
            end
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            value = app.YButton.Value;
            if value
                if app.Val==11
                    app.YButton.Value=false;
                    return
                end
                app.element(app.Val)="Y";
                app.button
            else
                app.remove="Y";
                app.xbutton
            end
        end

        % Value changed function: LaButton
        function LaButtonValueChanged(app, event)
            value = app.LaButton.Value;
            if value
                if app.Val==11
                    app.LaButton.Value=false;
                    return
                end
                app.element(app.Val)="La";
                app.button
            else
                app.remove="La";
                app.xbutton
            end
        end

        % Value changed function: TiButton
        function TiButtonValueChanged(app, event)
            value = app.TiButton.Value;
            if value
                if app.Val==11
                    app.TiButton.Value=false;
                    return
                end
                app.element(app.Val)="Ti";
                app.button
            else
                app.remove="Ti";
                app.xbutton
            end
        end

        % Value changed function: ZrButton
        function ZrButtonValueChanged(app, event)
            value = app.ZrButton.Value;
            if value
                if app.Val==11
                    app.ZrButton.Value=false;
                    return
                end
                app.element(app.Val)="Zr";
                app.button
            else
                app.remove="Zr";
                app.xbutton
            end
        end

        % Value changed function: HfButton
        function HfButtonValueChanged(app, event)
            value = app.HfButton.Value;
            if value
                if app.Val==11
                    app.HfButton.Value=false;
                    return
                end
                app.element(app.Val)="Hf";
                app.button
            else
                app.remove="Hf";
                app.xbutton
            end
        end

        % Value changed function: VButton
        function VButtonValueChanged(app, event)
            value = app.VButton.Value;
            if value
                if app.Val==11
                    app.VButton.Value=false;
                    return
                end
                app.element(app.Val)="V";
                app.button
            else
                app.remove="V";
                app.xbutton
            end
        end

        % Value changed function: CrButton
        function CrButtonValueChanged(app, event)
            value = app.CrButton.Value;
            if value
                if app.Val==11
                    app.CrButton.Value=false;
                    return
                end
                app.element(app.Val)="Cr";
                app.button
            else
                app.remove="Cr";
                app.xbutton
            end
        end

        % Value changed function: MnButton
        function MnButtonValueChanged(app, event)
            value = app.MnButton.Value;
            if value
                if app.Val==11
                    app.MnButton.Value=false;
                    return
                end
                app.element(app.Val)="Mn";
                app.button
            else
                app.remove="Mn";
                app.xbutton
            end
        end

        % Value changed function: FeButton
        function FeButtonValueChanged(app, event)
            value = app.FeButton.Value;
            if value
                if app.Val==11
                    app.FeButton.Value=false;
                    return
                end
                app.element(app.Val)="Fe";
                app.button
            else
                app.remove="Fe";
                app.xbutton
            end
        end

        % Value changed function: CoButton
        function CoButtonValueChanged(app, event)
            value = app.CoButton.Value;
            if value
                if app.Val==11
                    app.CoButton.Value=false;
                    return
                end
                app.element(app.Val)="Co";
                app.button
            else
                app.remove="Co";
                app.xbutton
            end
        end

        % Value changed function: CuButton
        function CuButtonValueChanged(app, event)
            value = app.CuButton.Value;
            if value
                if app.Val==11
                    app.CuButton.Value=false;
                    return
                end
                app.element(app.Val)="Cu";
                app.button
            else
                app.remove="Cu";
                app.xbutton
            end
        end

        % Value changed function: ZnButton
        function ZnButtonValueChanged(app, event)
            value = app.ZnButton.Value;
            if value
                if app.Val==11
                    app.ZnButton.Value=false;
                    return
                end
                app.element(app.Val)="Zn";
                app.button
            else
                app.remove="Zn";
                app.xbutton
            end
        end

        % Value changed function: NbButton
        function NbButtonValueChanged(app, event)
            value = app.NbButton.Value;
            if value
                if app.Val==11
                    app.NbButton.Value=false;
                    return
                end
                app.element(app.Val)="Nb";
                app.button
            else
                app.remove="Nb";
                app.xbutton
            end
        end

        % Value changed function: TaButton
        function TaButtonValueChanged(app, event)
            value = app.TaButton.Value;
            if value
                if app.Val==11
                    app.TaButton.Value=false;
                    return
                end
                app.element(app.Val)="Ta";
                app.button
            else
                app.remove="Ta";
                app.xbutton
            end
        end

        % Value changed function: MoButton
        function MoButtonValueChanged(app, event)
            value = app.MoButton.Value;
            if value
                if app.Val==11
                    app.MoButton.Value=false;
                    return
                end
                app.element(app.Val)="Mo";
                app.button
            else
                app.remove="Mo";
                app.xbutton
            end
        end

        % Value changed function: WButton
        function WButtonValueChanged(app, event)
            value = app.WButton.Value;
            if value
                if app.Val==11
                    app.WButton.Value=false;
                    return
                end
                app.element(app.Val)="W";
                app.button
            else
                app.remove="W";
                app.xbutton
            end
        end

        % Value changed function: TcButton
        function TcButtonValueChanged(app, event)
            value = app.TcButton.Value;
            if value
                if app.Val==11
                    app.TcButton.Value=false;
                    return
                end
                app.element(app.Val)="Tc";
                app.button
            else
                app.remove="Tc";
                app.xbutton
            end
        end

        % Value changed function: ReButton
        function ReButtonValueChanged(app, event)
            value = app.ReButton.Value;
            if value
                if app.Val==11
                    app.ReButton.Value=false;
                    return
                end
                app.element(app.Val)="Re";
                app.button
            else
                app.remove="Re";
                app.xbutton
            end
        end

        % Value changed function: RuButton
        function RuButtonValueChanged(app, event)
            value = app.RuButton.Value;
            if value
                if app.Val==11
                    app.RuButton.Value=false;
                    return
                end
                app.element(app.Val)="Ru";
                app.button
            else
                app.remove="Ru";
                app.xbutton
            end
        end

        % Value changed function: OsButton
        function OsButtonValueChanged(app, event)
            value = app.OsButton.Value;
            if value
                if app.Val==11
                    app.OsButton.Value=false;
                    return
                end
                app.element(app.Val)="Os";
                app.button
            else
                app.remove="Os";
                app.xbutton
            end
        end

        % Value changed function: GaButton
        function GaButtonValueChanged(app, event)
            value = app.GaButton.Value;
            if value
                if app.Val==11
                    app.GaButton.Value=false;
                    return
                end
                app.element(app.Val)="Ga";
                app.button
            else
                app.remove="Ga";
                app.xbutton
            end
        end

        % Value changed function: GeButton
        function GeButtonValueChanged(app, event)
            value = app.GeButton.Value;
            if value
                if app.Val==11
                    app.GeButton.Value=false;
                    return
                end
                app.element(app.Val)="Ge";
                app.button
            else
                app.remove="Ge";
                app.xbutton
            end
        end

        % Value changed function: AsButton
        function AsButtonValueChanged(app, event)
            value = app.AsButton.Value;
            if value
                if app.Val==11
                    app.AsButton.Value=false;
                    return
                end
                app.element(app.Val)="As";
                app.button
            else
                app.remove="As";
                app.xbutton
            end
        end

        % Value changed function: NiButton
        function NiButtonValueChanged(app, event)
            value = app.NiButton.Value;
            if value
                if app.Val==11
                    app.NiButton.Value=false;
                    return
                end
                app.element(app.Val)="Ni";
                app.button
            else
                app.remove="Ni";
                app.xbutton
            end
        end

        % Value changed function: EEditField_10
        function EEditField_10ValueChanged(app, event)
            value = app.EEditField_10.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+app.EEditField_4.Value+app.EEditField_5.Value+app.EEditField_6.Value+app.EEditField_7.Value+value+app.EEditField_8.Value+app.EEditField_9.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_9
        function EEditField_9ValueChanged(app, event)
            value = app.EEditField_9.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+app.EEditField_4.Value+app.EEditField_5.Value+app.EEditField_6.Value+app.EEditField_7.Value+value+app.EEditField_8.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_8
        function EEditField_8ValueChanged(app, event)
            value = app.EEditField_8.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+app.EEditField_4.Value+app.EEditField_5.Value+app.EEditField_6.Value+app.EEditField_7.Value+value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_7
        function EEditField_7ValueChanged(app, event)
            value = app.EEditField_7.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+app.EEditField_4.Value+app.EEditField_5.Value+app.EEditField_6.Value+value+app.EEditField_8.Value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_6
        function EEditField_6ValueChanged(app, event)
            value = app.EEditField_6.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+app.EEditField_4.Value+app.EEditField_5.Value+value+app.EEditField_7.Value+app.EEditField_8.Value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_5
        function EEditField_5ValueChanged(app, event)
            value = app.EEditField_5.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+app.EEditField_4.Value+value+app.EEditField_6.Value+app.EEditField_7.Value+app.EEditField_8.Value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_4
        function EEditField_4ValueChanged(app, event)
            value = app.EEditField_4.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+app.EEditField_3.Value+value+app.EEditField_5.Value+app.EEditField_6.Value+app.EEditField_7.Value+app.EEditField_8.Value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: EEditField_3
        function EEditField_3ValueChanged(app, event)
            value = app.EEditField_3.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=app.EEditField_2.Value+value+app.EEditField_4.Value+app.EEditField_5.Value+app.EEditField_6.Value+app.EEditField_7.Value+app.EEditField_8.Value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
            
        end

        % Value changed function: EEditField_2
        function EEditField_2ValueChanged(app, event)
            value = app.EEditField_2.Value;
            if app.AmountDropDown.Value=="Molar ratio"
                app.EEditField_1.Limits = [0 100];
            else
                values=value+app.EEditField_3.Value+app.EEditField_4.Value+app.EEditField_5.Value+app.EEditField_6.Value+app.EEditField_7.Value+app.EEditField_8.Value+app.EEditField_9.Value+app.EEditField_10.Value;
                if values>100
                    app.EEditField_1.Limits = [-10000 100];
                end
                app.EEditField_1.Value=100-values;
            end
        end

        % Value changed function: MC1CriterionCheckBox
        function MC1CriterionCheckBoxValueChanged(app, event)
            value = app.MC1CriterionCheckBox.Value;
            if value
                app.MC1DropDown.Enable = 'on';
                MC1DropDownValueChanged(app, event);
            else
                app.MC1DropDown.Enable = 'off';
                app.MC1DropDown.Value = "Select";
                MC1DropDownValueChanged(app, event);
            end
        end

        % Value changed function: MC2CriterionCheckBox
        function MC2CriterionCheckBoxValueChanged(app, event)
            value = app.MC2CriterionCheckBox.Value;
            if value
                app.MC2DropDown.Enable = 'on';
                MC2DropDownValueChanged(app, event);
            else
                app.MC2DropDown.Enable = 'off';
                app.MC2DropDown.Value ="Select";
                MC2DropDownValueChanged(app, event);
            end
        end

        % Value changed function: MC3CriterionCheckBox
        function MC3CriterionCheckBoxValueChanged(app, event)
            value = app.MC3CriterionCheckBox.Value;
            if value
                app.MC3DropDown.Enable = 'on';
                MC3DropDownValueChanged(app, event)
            else
                app.MC3DropDown.Enable = 'off';
                app.MC3DropDown.Value ="Select";
                MC3DropDownValueChanged(app, event);
            end
        end

        % Value changed function: MC4CriterionCheckBox
        function MC4CriterionCheckBoxValueChanged(app, event)
            value = app.MC4CriterionCheckBox.Value;
            if value
                app.MC4DropDown.Enable = 'on';
                MC4DropDownValueChanged(app, event)
            else
                app.MC4DropDown.Enable = 'off';
                app.MC4DropDown.Value ="Select";
                MC4DropDownValueChanged(app, event)
            end
        end

        % Value changed function: MC5CriterionCheckBox
        function MC5CriterionCheckBoxValueChanged(app, event)
            value = app.MC5CriterionCheckBox.Value;
            if value
                app.MC5DropDown.Enable = 'on';
                MC5DropDownValueChanged(app, event)
            else
                app.MC5DropDown.Enable = 'off';
                app.MC5DropDown.Value ="Select";
                MC5DropDownValueChanged(app, event)
            end
        end

        % Value changed function: MC6CriterionCheckBox
        function MC6CriterionCheckBoxValueChanged(app, event)
            value = app.MC6CriterionCheckBox.Value;
            if value
                app.MC6DropDown.Enable = 'on';
                MC6DropDownValueChanged(app, event);
            else
                app.MC6DropDown.Enable = 'off';
                app.MC6DropDown.Value = "Select";
                MC6DropDownValueChanged(app, event);
            end
        end

        % Value changed function: MC7CriterionCheckBox
        function MC7CriterionCheckBoxValueChanged(app, event)
            value = app.MC7CriterionCheckBox.Value;
            if value
                app.MC7DropDown.Enable = 'on';
                MC7DropDownValueChanged(app, event)
            else
                app.MC7DropDown.Enable = 'off';
                app.MC7DropDown.Value ="Select";
                MC7DropDownValueChanged(app, event)
            end
        end

        % Value changed function: MC9CriterionCheckBox
        function MC9CriterionCheckBoxValueChanged(app, event)
            value = app.MC9CriterionCheckBox.Value;
            if value
                app.MC9DropDown.Enable = 'on';
                MC9DropDownValueChanged(app, event)
            else
                app.MC9DropDown.Enable = 'off';
                app.MC9DropDown.Value = "Select";
                MC9DropDownValueChanged(app, event)
            end
        end

        % Value changed function: MC8CriterionCheckBox
        function MC8CriterionCheckBoxValueChanged(app, event)
            value = app.MC8CriterionCheckBox.Value;
            if value
                app.MC8DropDown.Enable = 'on';
                MC8DropDownValueChanged(app, event)
            else
                app.MC8DropDown.Enable = 'off';
                app.MC8DropDown.Value ="Select";
                MC8DropDownValueChanged(app, event)
            end
        end

        % Value changed function: MC10CriterionCheckBox
        function MC10CriterionCheckBoxValueChanged(app, event)
            value = app.MC10CriterionCheckBox.Value;
            if value
                app.MC10DropDown.Enable = 'on';
                MC10DropDownValueChanged(app, event)
            else
                app.MC10DropDown.Enable = 'off';
                app.MC10DropDown.Value = "Select";
                MC10DropDownValueChanged(app, event)
            end
        end

        % Value changed function: LSS1CriterionCheckBox
        function LSS1CriterionCheckBoxValueChanged(app, event)
            value = app.LSS1CriterionCheckBox.Value;
            if value
                app.LSS1DropDown.Enable = 'on';
                LSS1DropDownValueChanged(app, event)
            else
                app.LSS1DropDown.Enable = 'off';
                app.LSS1DropDown.Value = "Select";
                LSS1DropDownValueChanged(app, event)
            end
        end

        % Value changed function: LSS2CriterionCheckBox
        function LSS2CriterionCheckBoxValueChanged(app, event)
            value = app.LSS2CriterionCheckBox.Value;
            if value
                app.LSS2DropDown.Enable = 'on';
                LSS2DropDownValueChanged(app, event)
            else
                app.LSS2DropDown.Enable = 'off';
                app.LSS2DropDown.Value = "Select";
                LSS2DropDownValueChanged(app, event)
            end
        end

        % Value changed function: LSS3CriterionCheckBox
        function LSS3CriterionCheckBoxValueChanged(app, event)
            value = app.LSS3CriterionCheckBox.Value;
            if value
                app.LSS3DropDown.Enable = 'on';
                LSS3DropDownValueChanged(app, event)
            else
                app.LSS3DropDown.Enable = 'off';
                app.LSS3DropDown.Value = "Select";
                LSS3DropDownValueChanged(app, event)
            end
        end

        % Value changed function: LSS4CriterionCheckBox
        function LSS4CriterionCheckBoxValueChanged(app, event)
            value = app.LSS4CriterionCheckBox.Value;
            if value
                app.LSS4DropDown.Enable = 'on';
                LSS4DropDownValueChanged(app, event)
            else
                app.LSS4DropDown.Enable = 'off';
                app.LSS4DropDown.Value = "Select";
                LSS4DropDownValueChanged(app, event)
            end
        end

        % Value changed function: LSS5CriterionCheckBox
        function LSS5CriterionCheckBoxValueChanged(app, event)
            value = app.LSS5CriterionCheckBox.Value;
            if value
                app.LSS5DropDown.Enable = 'on';
                LSS5DropDownValueChanged(app, event)
            else
                app.LSS5DropDown.Enable = 'off';
                app.LSS5DropDown.Value = "Select";
                LSS5DropDownValueChanged(app, event)
            end
        end

        % Value changed function: ImF1CriterionCheckBox
        function ImF1CriterionCheckBoxValueChanged(app, event)
            value = app.ImF1CriterionCheckBox.Value;
            if value
                app.ImF1DropDown.Enable = 'on';
                ImF1DropDownValueChanged(app, event)
            else
                app.ImF1DropDown.Enable = 'off';
                app.ImF1DropDown.Value = "Select";
                ImF1DropDownValueChanged(app, event)
            end
        end

        % Value changed function: ImF2CriterionCheckBox
        function ImF2CriterionCheckBoxValueChanged(app, event)
            value = app.ImF2CriterionCheckBox.Value;
            if value
                app.ImF2DropDown.Enable = 'on';
                ImF2DropDownValueChanged(app, event)
            else
                app.ImF2DropDown.Enable = 'off';
                app.ImF2DropDown.Value="Select";
                ImF2DropDownValueChanged(app, event)
            end
        end

        % Value changed function: ImF3CriterionCheckBox
        function ImF3CriterionCheckBoxValueChanged(app, event)
            value = app.ImF3CriterionCheckBox.Value;
            if value
                app.ImF3DropDown.Enable = 'on';
                ImF3DropDownValueChanged(app, event)
            else
                app.ImF3DropDown.Enable = 'off';
                app.ImF3DropDown.Value="Select";
                ImF3DropDownValueChanged(app, event)
            end
        end

        % Value changed function: FMP1CriterionCheckBox
        function FMP1CriterionCheckBoxValueChanged(app, event)
            value = app.FMP1CriterionCheckBox.Value;
            if value
                app.FMP1DropDown.Enable = 'on';
                FMP1DropDownValueChanged(app, event)
            else
                app.FMP1DropDown.Enable = 'off';
                app.FMP1DropDown.Value="Select";
                FMP1DropDownValueChanged(app, event)
            end
        end

        % Value changed function: MC1DropDown
        function MC1DropDownValueChanged(app, event)
            value = app.MC1DropDown.Value;
            if value=="Select"
                app.r8.Enable='on';
                app.R8.Enable='on';
                app.R14.Enable='on';
                app.r14.Enable='on';
                if app.MC2DropDown.Value~="Select"
                    MC2DropDownValueChanged(app, event)
                end
                if app.MC3DropDown.Value~="Select"
                    MC3DropDownValueChanged(app,event)
                end
                if app.MC5DropDown.Value~="Select"
                    MC5DropDownValueChanged(app,event)
                end
                if app.MC7DropDown.Value~="Select"
                    MC7DropDownValueChanged(app,event)
                end
                if app.ImF3DropDown.Value~="Select"
                    ImF3DropDownValueChanged(app,event)
                end
            else
                app.R8.Enable='off';
                app.R14.Enable='off';
                app.r8.Enable='off';
                app.r14.Enable='off';
                
            end
        end

        % Value changed function: MC2DropDown
        function MC2DropDownValueChanged(app, event)
            value = app.MC2DropDown.Value;
            if value=="Select"
                app.R8.Enable='on';
                app.r19.Enable='on';
                app.r8.Enable='on';
                app.R19.Enable='on';
                if app.MC1DropDown.Value~="Select"
                    MC1DropDownValueChanged(app, event);
                end
                if app.MC3DropDown.Value~="Select"
                    MC3DropDownValueChanged(app,event)
                end
                if app.MC5DropDown.Value~="Select"
                    MC5DropDownValueChanged(app,event)
                end
                if app.MC7DropDown.Value~="Select"
                    MC7DropDownValueChanged(app,event)
                end
                if app.ImF3DropDown.Value~="Select"
                    ImF3DropDownValueChanged(app,event)
                end
            
            else
                app.R8.Enable='off';
                app.r19.Enable='off';
                app.r8.Enable='off';
                app.R19.Enable='off';

            end
        end

        % Value changed function: MC3DropDown
        function MC3DropDownValueChanged(app, event)
            value = app.MC3DropDown.Value;
            if value=="Select"
                app.R8.Enable='on';
                app.r14.Enable='on';
                app.R14.Enable='on';
                app.r8.Enable='on';
                if app.MC2DropDown.Value~="Select"
                    MC2DropDownValueChanged(app, event)
                end
                if app.MC1DropDown.Value~="Select"
                    MC1DropDownValueChanged(app,event)
                end
                if app.MC5DropDown.Value~="Select"
                    MC5DropDownValueChanged(app,event)
                end
                if app.MC7DropDown.Value~="Select"
                    MC7DropDownValueChanged(app,event)
                end
                if app.ImF3DropDown.Value~="Select"
                    ImF3DropDownValueChanged(app,event)
                end
            else
                app.R8.Enable='off';
                app.r14.Enable='off';
                app.R14.Enable='off';
                app.r8.Enable='off';
            end
        end

        % Value changed function: MC4DropDown
        function MC4DropDownValueChanged(app, event)
            value = app.MC4DropDown.Value;
            if value=="Select"
                app.R18.Enable='on';
                app.r18.Enable='on';
            else
                app.r18.Enable='off';
                app.R18.Enable='off';
            end
        end

        % Value changed function: MC5DropDown
        function MC5DropDownValueChanged(app, event)
            value = app.MC5DropDown.Value;
            if value=="Select"
                app.r8.Enable='on';
                app.R8.Enable='on';
                app.r11.Enable='on';
                app.R11.Enable='on';
                if app.MC2DropDown.Value~="Select"
                    MC2DropDownValueChanged(app, event)
                end
                if app.MC1DropDown.Value~="Select"
                    MC1DropDownValueChanged(app,event)
                end
                if app.MC3DropDown.Value~="Select"
                    MC3DropDownValueChanged(app,event)
                end
                if app.MC7DropDown.Value~="Select"
                    MC7DropDownValueChanged(app,event)
                end
                if app.ImF3DropDown.Value~="Select"
                    ImF3DropDownValueChanged(app,event)
                end
            else
                app.r8.Enable='off';
                app.R8.Enable='off';
                app.r11.Enable='off';
                app.R11.Enable='off';
            end
        end

        % Value changed function: MC6DropDown
        function MC6DropDownValueChanged(app, event)
            value = app.MC6DropDown.Value;
            if value=="Select"
                app.R20.Enable='on';
                app.r20.Enable='on';
            else
                app.R20.Enable='off';
                app.r20.Enable='off';
            end
        end

        % Value changed function: MC7DropDown
        function MC7DropDownValueChanged(app, event)
            value = app.MC7DropDown.Value;
            if value=="Select"
                app.R8.Enable='on';
                app.r14.Enable='on';
                app.R14.Enable='on';
                app.r8.Enable='on';
                app.R1.Enable='on';
                app.r1.Enable='on';
                if app.MC2DropDown.Value~="Select"
                    MC2DropDownValueChanged(app, event)
                end
                if app.MC1DropDown.Value~="Select"
                    MC1DropDownValueChanged(app,event)
                end
                if app.MC5DropDown.Value~="Select"
                    MC5DropDownValueChanged(app,event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.MC3DropDown.Value~="Select"
                    MC3DropDownValueChanged(app,event)
                end                
            else
                app.R8.Enable='off';
                app.r14.Enable='off';
                app.R14.Enable='off';
                app.r8.Enable='off';
                app.R1.Enable='off';
                app.r1.Enable='off';
            end
        end

        % Value changed function: MC9DropDown
        function MC9DropDownValueChanged(app, event)
            value = app.MC9DropDown.Value;
            if value=="Select"
                app.r22.Enable='on';
                app.R22.Enable='on';
            else
                app.r22.Enable='off';
                app.R22.Enable='off';
            end
        end

        % Value changed function: MC8DropDown
        function MC8DropDownValueChanged(app, event)
            value = app.MC8DropDown.Value;
            if value=="Select"
                app.r21.Enable='on';
                app.R21.Enable='on';
            else
                app.r21.Enable='off';
                app.R21.Enable='off';
            end
        end

        % Value changed function: MC10DropDown
        function MC10DropDownValueChanged(app, event)
            value = app.MC10DropDown.Value;
            if value=="Select"
                app.r23.Enable='on';
                app.R23.Enable='on';
            else
                app.R23.Enable='off';
                app.r23.Enable='off';
            end
        end

        % Value changed function: LSS1DropDown
        function LSS1DropDownValueChanged(app, event)
            value = app.LSS1DropDown.Value;
            if value=="Select"
                app.r6.Enable='on';
                app.R6.Enable='on';
                if app.LSS2DropDown.Value~="Select"
                    LSS2DropDownValueChanged(app, event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.LSS4DropDown.Value~="Select"
                    LSS4DropDownValueChanged(app,event)
                end
                if app.LSS5DropDown.Value~="Select"
                    LSS5DropDownValueChanged(app,event)
                end
                if app.FMP1DropDown.Value~="Select"
                    FMP1DropDownValueChanged(app, event)
                end
                if app.ImF2DropDown.Value~="Select"
                    ImF2DropDownValueChanged(app, event)
                end
            else
                app.R6.Enable='off';
                app.r6.Enable='off';
            end
        end

        % Value changed function: LSS2DropDown
        function LSS2DropDownValueChanged(app, event)
            value = app.LSS2DropDown.Value;
            if value=="Select"
                app.r7.Enable='on';
                app.R7.Enable='on';
                app.r6.Enable='on';
                app.R6.Enable='on';
                if app.LSS1DropDown.Value~="Select"
                    LSS1DropDownValueChanged(app, event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.LSS4DropDown.Value~="Select"
                    LSS4DropDownValueChanged(app,event)
                end
                if app.LSS5DropDown.Value~="Select"
                    LSS5DropDownValueChanged(app,event)
                end
                if app.FMP1DropDown.Value~="Select"
                    FMP1DropDownValueChanged(app, event)
                end
                if app.ImF2DropDown.Value~="Select"
                    ImF2DropDownValueChanged(app, event)
                end
            else
                app.r7.Enable='off';
                app.R7.Enable='off';
                app.r6.Enable='off';
                app.R6.Enable='off';
            end
        end

        % Value changed function: LSS3DropDown
        function LSS3DropDownValueChanged(app, event)
            value = app.LSS3DropDown.Value;
            T=app.TemperatureEditField.Value;
            if value=="Select"
                app.r6.Enable='on';
                app.R6.Enable='on';
                app.R1.Enable='on';
                app.r1.Enable='on';
                if app.LSS1DropDown.Value~="Select"
                    LSS1DropDownValueChanged(app, event)
                end
                if app.LSS2DropDown.Value~="Select"
                    LSS2DropDownValueChanged(app,event)
                end
                if app.LSS4DropDown.Value~="Select"
                    LSS4DropDownValueChanged(app,event)
                end
                if app.MC7DropDown.Value~="Select"
                    MC7DropDownValueChanged(app,event)
                end
                if app.LSS5DropDown.Value~="Select"
                    LSS5DropDownValueChanged(app,event)
                end
                if app.FMP1DropDown.Value~="Select"
                    FMP1DropDownValueChanged(app, event)
                end
                if app.ImF2DropDown.Value~="Select"
                    ImF2DropDownValueChanged(app, event)
                end
            else
                app.R1.Enable='off';
                app.r1.Enable='off';
                app.r6.Enable='off';
                app.R6.Enable='off'; 
            end
        end

        % Value changed function: LSS4DropDown
        function LSS4DropDownValueChanged(app, event)
            value = app.LSS4DropDown.Value;
            if value=="Select"
                app.r21.Enable='on';
                app.r6.Enable='on';
                app.R6.Enable='on';
                app.R21.Enable='on';
                if app.LSS1DropDown.Value~="Select"
                    LSS1DropDownValueChanged(app, event)
                end
                if app.LSS2DropDown.Value~="Select"
                    LSS2DropDownValueChanged(app,event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.MC8DropDown.Value~="Select"
                    MC8DropDownValueChanged(app,event)
                end
                if app.LSS5DropDown.Value~="Select"
                    LSS5DropDownValueChanged(app,event)
                end
                if app.FMP1DropDown.Value~="Select"
                    FMP1DropDownValueChanged(app, event)
                end
                if app.ImF2DropDown.Value~="Select"
                    ImF2DropDownValueChanged(app, event)
                end
            else
                app.r6.Enable='off';
                app.R6.Enable='off';
                app.r21.Enable='off';
                app.R21.Enable='off';
                
            end
        end

        % Value changed function: LSS5DropDown
        function LSS5DropDownValueChanged(app, event)
            value = app.LSS5DropDown.Value;
            if value=="Select"
                app.r6.Enable='on';
                app.R6.Enable='on';
                app.r3.Enable='on';
                app.R3.Enable='on';
                app.r12.Enable='on';
                app.R12.Enable='on';
                app.r13.Enable='on';
                app.R13.Enable='on';
                if app.LSS1DropDown.Value~="Select"
                    LSS1DropDownValueChanged(app, event)
                end
                if app.LSS2DropDown.Value~="Select"
                    LSS2DropDownValueChanged(app,event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.LSS4DropDown.Value~="Select"
                    LSS4DropDownValueChanged(app,event)
                end
                
                if app.FMP1DropDown.Value~="Select"
                    FMP1DropDownValueChanged(app, event)
                end
                if app.ImF2DropDown.Value~="Select"
                    ImF2DropDownValueChanged(app, event)
                end
            else
                app.r6.Enable='off';
                app.R6.Enable='off';
                app.r3.Enable='off';
                app.R3.Enable='off';
                app.r12.Enable='off';
                app.R12.Enable='off';
                app.r13.Enable='off';
                app.R13.Enable='off';
            end
        end

        % Value changed function: ImF1DropDown
        function ImF1DropDownValueChanged(app, event)
            value = app.ImF1DropDown.Value;
            if value=="Select"
                app.r10.Enable='on';
                app.R10.Enable='on';
            else
                app.r10.Enable='off';
                app.R10.Enable='off';
            end
        end

        % Value changed function: ImF2DropDown
        function ImF2DropDownValueChanged(app, event)
            value = app.ImF2DropDown.Value;
            if value=="Select"
                app.r6.Enable='on';
                app.R6.Enable='on';
                app.r24.Enable='on';
                app.R24.Enable='on';
                if app.LSS1DropDown.Value~="Select"
                    LSS1DropDownValueChanged(app, event)
                end
                if app.LSS2DropDown.Value~="Select"
                    LSS2DropDownValueChanged(app,event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.LSS4DropDown.Value~="Select"
                    LSS4DropDownValueChanged(app,event)
                end
                if app.LSS5DropDown.Value~="Select"
                    LSS5DropDownValueChanged(app,event)
                end
                
                if app.FMP1DropDown.Value~="Select"
                    FMP1DropDownValueChanged(app, event)
                end
            else
                app.r6.Enable='off';
                app.R6.Enable='off';
                app.R24.Enable='off';
                app.r24.Enable='off';
            end
        end

        % Value changed function: ImF3DropDown
        function ImF3DropDownValueChanged(app, event)
            value = app.ImF3DropDown.Value;
            if value=="Select"
                app.r11.Enable='on';
                app.r8.Enable='on';
                app.R11.Enable='on';
                app.R8.Enable='on';
                if app.MC2DropDown.Value~="Select"
                    MC2DropDownValueChanged(app, event)
                end
                if app.MC1DropDown.Value~="Select"
                    MC1DropDownValueChanged(app,event)
                end
                if app.MC3DropDown.Value~="Select"
                    MC3DropDownValueChanged(app,event)
                end
                if app.MC7DropDown.Value~="Select"
                    MC7DropDownValueChanged(app,event)
                end
                if app.MC5DropDown.Value~="Select"
                    MC5DropDownValueChanged(app,event)
                end
            else
                app.R11.Enable='off';
                app.R8.Enable='off';   
                app.r11.Enable='off';
                app.r8.Enable='off';
            end
        end

        % Value changed function: FMP1DropDown
        function FMP1DropDownValueChanged(app, event)
            value = app.FMP1DropDown.Value;
            if value=="Select"
                app.R6.Enable='on';
                app.r6.Enable='on';
                if app.ImF2DropDown.Value~="Select"
                    ImF2DropDownValueChanged(app, event)
                end
                if app.LSS2DropDown.Value~="Select"
                    LSS2DropDownValueChanged(app, event)
                end
                if app.LSS3DropDown.Value~="Select"
                    LSS3DropDownValueChanged(app,event)
                end
                if app.LSS4DropDown.Value~="Select"
                    LSS4DropDownValueChanged(app,event)
                end
                if app.LSS5DropDown.Value~="Select"
                    LSS5DropDownValueChanged(app,event)
                end
                if app.LSS1DropDown.Value~="Select"
                    LSS1DropDownValueChanged(app, event)
                end
            else
                app.R6.Enable='off';
                app.r6.Enable='off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HEAPS = uifigure('Visible', 'off');
            app.HEAPS.Name = 'High-Entropy Alloys Predicting Software';
            app.GridLayout3 = uigridlayout(app.HEAPS);
            app.Calculate = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Calculate);
            app.OptionCalculate = uibuttongroup(app.GridLayout4);
            app.OptionCalculate.SelectionChangedFcn = createCallbackFcn(app, @OptionCalculateSelectionChanged, true);
            app.SinglecalculationButton = uiradiobutton(app.OptionCalculate);
            app.SinglecalculationButton.Text = 'Single calculation';
            app.SinglecalculationButton.Value = true;
            app.ExplorerButton = uiradiobutton(app.OptionCalculate);
            app.ExplorerButton.Text = 'Explorer';
            app.Panel0 = uipanel(app.GridLayout4);
            app.ElementLabel = uilabel(app.Panel0);
            app.ElementLabel.Text = 'Element';
            app.AmountDropDown = uidropdown(app.Panel0);
            app.AmountDropDown.Items = {'Composition scale', 'Molar ratio', 'Weight percent', 'Atomic percent'};
            app.AmountDropDown.ValueChangedFcn = createCallbackFcn(app, @AmountDropDownValueChanged, true);
            app.AmountDropDown.Value = 'Molar ratio';
            app.FixedLabel = uilabel(app.Panel0);
            app.FixedLabel.Text = 'Fixed';
            app.FromLabel = uilabel(app.Panel0);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.Panel0);
            app.ToLabel.Text = 'To';
            app.E_1 = uipanel(app.GridLayout4);
            app.E_1.Visible = 'off';
            app.ELabel_1 = uilabel(app.E_1);
            app.ELabel_1.Text = '-';
            app.EEditField_1 = uieditfield(app.E_1, 'numeric');
            app.EEditField_1.Limits = [0 100];
            app.EEditField_1.Value = 1;
            app.EFixCheckBox_1 = uicheckbox(app.E_1);
            app.EFixCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_1ValueChanged, true);
            app.EFixCheckBox_1.Enable = 'off';
            app.EFixCheckBox_1.Text = '';
            app.EFixCheckBox_1.Value = true;
            app.EFromEditField_1 = uieditfield(app.E_1, 'numeric');
            app.EFromEditField_1.Limits = [0 100];
            app.EFromEditField_1.Enable = 'off';
            app.EToEditField_1 = uieditfield(app.E_1, 'numeric');
            app.EToEditField_1.Limits = [0 100];
            app.EToEditField_1.Enable = 'off';
            app.EToEditField_1.Value = 1;
            app.E_2 = uipanel(app.GridLayout4);
            app.E_2.Visible = 'off';
            app.ELabel_2 = uilabel(app.E_2);
            app.ELabel_2.Text = '-';
            app.EEditField_2 = uieditfield(app.E_2, 'numeric');
            app.EEditField_2.Limits = [0 100];
            app.EEditField_2.ValueChangedFcn = createCallbackFcn(app, @EEditField_2ValueChanged, true);
            app.EEditField_2.Value = 1;
            app.EFixCheckBox_2 = uicheckbox(app.E_2);
            app.EFixCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_2ValueChanged, true);
            app.EFixCheckBox_2.Enable = 'off';
            app.EFixCheckBox_2.Text = '';
            app.EFixCheckBox_2.Value = true;
            app.EFromEditField_2 = uieditfield(app.E_2, 'numeric');
            app.EFromEditField_2.Limits = [0 100];
            app.EFromEditField_2.Enable = 'off';
            app.EToEditField_2 = uieditfield(app.E_2, 'numeric');
            app.EToEditField_2.Limits = [0 100];
            app.EToEditField_2.Enable = 'off';
            app.EToEditField_2.Value = 1;
            app.E_3 = uipanel(app.GridLayout4);
            app.E_3.Visible = 'off';
            app.ELabel_3 = uilabel(app.E_3);
            app.ELabel_3.Text = '-';
            app.EEditField_3 = uieditfield(app.E_3, 'numeric');
            app.EEditField_3.Limits = [0 100];
            app.EEditField_3.ValueChangedFcn = createCallbackFcn(app, @EEditField_3ValueChanged, true);
            app.EEditField_3.Value = 1;
            app.EFixCheckBox_3 = uicheckbox(app.E_3);
            app.EFixCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_3ValueChanged, true);
            app.EFixCheckBox_3.Enable = 'off';
            app.EFixCheckBox_3.Text = '';
            app.EFixCheckBox_3.Value = true;
            app.EFromEditField_3 = uieditfield(app.E_3, 'numeric');
            app.EFromEditField_3.Limits = [0 100];
            app.EFromEditField_3.Enable = 'off';
            app.EToEditField_3 = uieditfield(app.E_3, 'numeric');
            app.EToEditField_3.Limits = [0 100];
            app.EToEditField_3.Enable = 'off';
            app.EToEditField_3.Value = 1;
            app.E_4 = uipanel(app.GridLayout4);
            app.E_4.Visible = 'off';
            app.ELabel_4 = uilabel(app.E_4);
            app.ELabel_4.Text = '-';
            app.EEditField_4 = uieditfield(app.E_4, 'numeric');
            app.EEditField_4.Limits = [0 100];
            app.EEditField_4.ValueChangedFcn = createCallbackFcn(app, @EEditField_4ValueChanged, true);
            app.EEditField_4.Value = 1;
            app.EFixCheckBox_4 = uicheckbox(app.E_4);
            app.EFixCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_4ValueChanged, true);
            app.EFixCheckBox_4.Enable = 'off';
            app.EFixCheckBox_4.Text = '';
            app.EFixCheckBox_4.Value = true;
            app.EFromEditField_4 = uieditfield(app.E_4, 'numeric');
            app.EFromEditField_4.Limits = [0 100];
            app.EFromEditField_4.Enable = 'off';
            app.EToEditField_4 = uieditfield(app.E_4, 'numeric');
            app.EToEditField_4.Limits = [0 100];
            app.EToEditField_4.Enable = 'off';
            app.EToEditField_4.Value = 1;
            app.E_5 = uipanel(app.GridLayout4);
            app.E_5.Visible = 'off';
            app.ELabel_5 = uilabel(app.E_5);
            app.ELabel_5.Text = '-';
            app.EEditField_5 = uieditfield(app.E_5, 'numeric');
            app.EEditField_5.Limits = [0 100];
            app.EEditField_5.ValueChangedFcn = createCallbackFcn(app, @EEditField_5ValueChanged, true);
            app.EEditField_5.Value = 1;
            app.EFixCheckBox_5 = uicheckbox(app.E_5);
            app.EFixCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_5ValueChanged, true);
            app.EFixCheckBox_5.Enable = 'off';
            app.EFixCheckBox_5.Text = '';
            app.EFixCheckBox_5.Value = true;
            app.EFromEditField_5 = uieditfield(app.E_5, 'numeric');
            app.EFromEditField_5.Limits = [0 100];
            app.EFromEditField_5.Enable = 'off';
            app.EToEditField_5 = uieditfield(app.E_5, 'numeric');
            app.EToEditField_5.Limits = [0 100];
            app.EToEditField_5.Enable = 'off';
            app.EToEditField_5.Value = 1;
            app.E_6 = uipanel(app.GridLayout4);
            app.E_6.Visible = 'off';
            app.ELabel_6 = uilabel(app.E_6);
            app.ELabel_6.Text = '-';
            app.EEditField_6 = uieditfield(app.E_6, 'numeric');
            app.EEditField_6.Limits = [0 100];
            app.EEditField_6.ValueChangedFcn = createCallbackFcn(app, @EEditField_6ValueChanged, true);
            app.EEditField_6.Value = 1;
            app.EFixCheckBox_6 = uicheckbox(app.E_6);
            app.EFixCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_6ValueChanged, true);
            app.EFixCheckBox_6.Enable = 'off';
            app.EFixCheckBox_6.Text = '';
            app.EFixCheckBox_6.Value = true;
            app.EFromEditField_6 = uieditfield(app.E_6, 'numeric');
            app.EFromEditField_6.Limits = [0 100];
            app.EFromEditField_6.Enable = 'off';
            app.EToEditField_6 = uieditfield(app.E_6, 'numeric');
            app.EToEditField_6.Limits = [0 100];
            app.EToEditField_6.Enable = 'off';
            app.EToEditField_6.Value = 1;
            app.E_7 = uipanel(app.GridLayout4);
            app.E_7.Visible = 'off';
            app.ELabel_7 = uilabel(app.E_7);
            app.ELabel_7.Text = '-';
            app.EEditField_7 = uieditfield(app.E_7, 'numeric');
            app.EEditField_7.Limits = [0 100];
            app.EEditField_7.ValueChangedFcn = createCallbackFcn(app, @EEditField_7ValueChanged, true);
            app.EEditField_7.Value = 1;
            app.EFixCheckBox_7 = uicheckbox(app.E_7);
            app.EFixCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_7ValueChanged, true);
            app.EFixCheckBox_7.Enable = 'off';
            app.EFixCheckBox_7.Text = '';
            app.EFixCheckBox_7.Value = true;
            app.EFromEditField_7 = uieditfield(app.E_7, 'numeric');
            app.EFromEditField_7.Limits = [0 100];
            app.EFromEditField_7.Enable = 'off';
            app.EToEditField_7 = uieditfield(app.E_7, 'numeric');
            app.EToEditField_7.Limits = [0 100];
            app.EToEditField_7.Enable = 'off';
            app.EToEditField_7.Value = 1;
            app.E_8 = uipanel(app.GridLayout4);
            app.E_8.Visible = 'off';
            app.ELabel_8 = uilabel(app.E_8);
            app.ELabel_8.Text = '-';
            app.EEditField_8 = uieditfield(app.E_8, 'numeric');
            app.EEditField_8.Limits = [0 100];
            app.EEditField_8.ValueChangedFcn = createCallbackFcn(app, @EEditField_8ValueChanged, true);
            app.EEditField_8.Value = 1;
            app.EFixCheckBox_8 = uicheckbox(app.E_8);
            app.EFixCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_8ValueChanged, true);
            app.EFixCheckBox_8.Enable = 'off';
            app.EFixCheckBox_8.Text = '';
            app.EFixCheckBox_8.Value = true;
            app.EFromEditField_8 = uieditfield(app.E_8, 'numeric');
            app.EFromEditField_8.Limits = [0 100];
            app.EFromEditField_8.Enable = 'off';
            app.EToEditField_8 = uieditfield(app.E_8, 'numeric');
            app.EToEditField_8.Limits = [0 100];
            app.EToEditField_8.Enable = 'off';
            app.EToEditField_8.Value = 1;
            app.E_9 = uipanel(app.GridLayout4);
            app.E_9.Visible = 'off';
            app.ELabel_9 = uilabel(app.E_9);
            app.ELabel_9.Text = '-';
            app.EEditField_9 = uieditfield(app.E_9, 'numeric');
            app.EEditField_9.Limits = [0 100];
            app.EEditField_9.ValueChangedFcn = createCallbackFcn(app, @EEditField_9ValueChanged, true);
            app.EEditField_9.Value = 1;
            app.EFixCheckBox_9 = uicheckbox(app.E_9);
            app.EFixCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_9ValueChanged, true);
            app.EFixCheckBox_9.Enable = 'off';
            app.EFixCheckBox_9.Text = '';
            app.EFixCheckBox_9.Value = true;
            app.EFromEditField_9 = uieditfield(app.E_9, 'numeric');
            app.EFromEditField_9.Limits = [0 100];
            app.EFromEditField_9.Enable = 'off';
            app.EToEditField_9 = uieditfield(app.E_9, 'numeric');
            app.EToEditField_9.Limits = [0 100];
            app.EToEditField_9.Enable = 'off';
            app.EToEditField_9.Value = 1;
            app.E_10 = uipanel(app.GridLayout4);
            app.E_10.Visible = 'off';
            app.ELabel_10 = uilabel(app.E_10);
            app.ELabel_10.Text = '-';
            app.EEditField_10 = uieditfield(app.E_10, 'numeric');
            app.EEditField_10.Limits = [0 100];
            app.EEditField_10.ValueChangedFcn = createCallbackFcn(app, @EEditField_10ValueChanged, true);
            app.EEditField_10.Value = 1;
            app.EFixCheckBox_10 = uicheckbox(app.E_10);
            app.EFixCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @EFixCheckBox_10ValueChanged, true);
            app.EFixCheckBox_10.Enable = 'off';
            app.EFixCheckBox_10.Text = '';
            app.EFixCheckBox_10.Value = true;
            app.EFromEditField_10 = uieditfield(app.E_10, 'numeric');
            app.EFromEditField_10.Limits = [0 100];
            app.EFromEditField_10.Enable = 'off';
            app.EToEditField_10 = uieditfield(app.E_10, 'numeric');
            app.EToEditField_10.Limits = [0 100];
            app.EToEditField_10.Enable = 'off';
            app.EToEditField_10.Value = 1;
            app.TemtStep = uipanel(app.GridLayout4);
            app.StepsizeLabel = uilabel(app.TemtStep);
            app.StepsizeLabel.Enable = 'off';
            app.StepsizeLabel.Text = 'Stepsize';
            app.StepsizeEditField = uieditfield(app.TemtStep, 'numeric');
            app.StepsizeEditField.Limits = [0 100];
            app.StepsizeEditField.Enable = 'off';
            app.StepsizeEditField.Value = 0.1;
            app.ApplyrestrictionsCheckBox = uicheckbox(app.TemtStep);
            app.ApplyrestrictionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyrestrictionsCheckBoxValueChanged, true);
            app.ApplyrestrictionsCheckBox.Enable = 'off';
            app.ApplyrestrictionsCheckBox.Text = 'Apply restrictions';
            app.FiltersButton = uibutton(app.TemtStep, 'push');
            app.FiltersButton.ButtonPushedFcn = createCallbackFcn(app, @FiltersButtonPushed, true);
            app.FiltersButton.Enable = 'off';
            app.FiltersButton.Text = '<<Filters';
            app.CalculateButton = uibutton(app.TemtStep, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RebootButton = uibutton(app.TemtStep, 'push');
            app.RebootButton.ButtonPushedFcn = createCallbackFcn(app, @RebootButtonButtonPushed, true);
            app.RebootButton.Enable = 'off';
            app.RebootButton.Text = 'Reboot';
            app.TemperatureLabel = uilabel(app.TemtStep);
            app.TemperatureLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.TemtStep, 'numeric');
            app.TemperatureEditField.Limits = [0 10000];
            app.TemperatureEditField.Value = 1000;
            app.KLabel = uilabel(app.TemtStep);
            app.KLabel.Text = 'K';
            app.ExportButton = uibutton(app.TemtStep, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.Elements = uipanel(app.GridLayout3);
            app.GridLayout = uigridlayout(app.Elements);
            app.AsButton = uibutton(app.GridLayout, 'state');
            app.AsButton.ValueChangedFcn = createCallbackFcn(app, @AsButtonValueChanged, true);
            app.AsButton.Text = 'As';
            app.GeButton = uibutton(app.GridLayout, 'state');
            app.GeButton.ValueChangedFcn = createCallbackFcn(app, @GeButtonValueChanged, true);
            app.GeButton.Text = 'Ge';
            app.GaButton = uibutton(app.GridLayout, 'state');
            app.GaButton.ValueChangedFcn = createCallbackFcn(app, @GaButtonValueChanged, true);
            app.GaButton.Text = 'Ga';
            app.OsButton = uibutton(app.GridLayout, 'state');
            app.OsButton.ValueChangedFcn = createCallbackFcn(app, @OsButtonValueChanged, true);
            app.OsButton.Text = 'Os';
            app.RuButton = uibutton(app.GridLayout, 'state');
            app.RuButton.ValueChangedFcn = createCallbackFcn(app, @RuButtonValueChanged, true);
            app.RuButton.Text = 'Ru';
            app.ReButton = uibutton(app.GridLayout, 'state');
            app.ReButton.ValueChangedFcn = createCallbackFcn(app, @ReButtonValueChanged, true);
            app.ReButton.Text = 'Re';
            app.TcButton = uibutton(app.GridLayout, 'state');
            app.TcButton.ValueChangedFcn = createCallbackFcn(app, @TcButtonValueChanged, true);
            app.TcButton.Text = 'Tc';
            app.WButton = uibutton(app.GridLayout, 'state');
            app.WButton.ValueChangedFcn = createCallbackFcn(app, @WButtonValueChanged, true);
            app.WButton.Text = 'W';
            app.MoButton = uibutton(app.GridLayout, 'state');
            app.MoButton.ValueChangedFcn = createCallbackFcn(app, @MoButtonValueChanged, true);
            app.MoButton.Text = 'Mo';
            app.TaButton = uibutton(app.GridLayout, 'state');
            app.TaButton.ValueChangedFcn = createCallbackFcn(app, @TaButtonValueChanged, true);
            app.TaButton.Text = 'Ta';
            app.NbButton = uibutton(app.GridLayout, 'state');
            app.NbButton.ValueChangedFcn = createCallbackFcn(app, @NbButtonValueChanged, true);
            app.NbButton.Text = 'Nb';
            app.ZnButton = uibutton(app.GridLayout, 'state');
            app.ZnButton.ValueChangedFcn = createCallbackFcn(app, @ZnButtonValueChanged, true);
            app.ZnButton.Text = 'Zn';
            app.CuButton = uibutton(app.GridLayout, 'state');
            app.CuButton.ValueChangedFcn = createCallbackFcn(app, @CuButtonValueChanged, true);
            app.CuButton.Text = 'Cu';
            app.NiButton = uibutton(app.GridLayout, 'state');
            app.NiButton.ValueChangedFcn = createCallbackFcn(app, @NiButtonValueChanged, true);
            app.NiButton.Text = 'Ni';
            app.CoButton = uibutton(app.GridLayout, 'state');
            app.CoButton.ValueChangedFcn = createCallbackFcn(app, @CoButtonValueChanged, true);
            app.CoButton.Text = 'Co';
            app.FeButton = uibutton(app.GridLayout, 'state');
            app.FeButton.ValueChangedFcn = createCallbackFcn(app, @FeButtonValueChanged, true);
            app.FeButton.Text = 'Fe';
            app.MnButton = uibutton(app.GridLayout, 'state');
            app.MnButton.ValueChangedFcn = createCallbackFcn(app, @MnButtonValueChanged, true);
            app.MnButton.Text = 'Mn';
            app.CrButton = uibutton(app.GridLayout, 'state');
            app.CrButton.ValueChangedFcn = createCallbackFcn(app, @CrButtonValueChanged, true);
            app.CrButton.Text = 'Cr';
            app.VButton = uibutton(app.GridLayout, 'state');
            app.VButton.ValueChangedFcn = createCallbackFcn(app, @VButtonValueChanged, true);
            app.VButton.Text = 'V';
            app.HfButton = uibutton(app.GridLayout, 'state');
            app.HfButton.ValueChangedFcn = createCallbackFcn(app, @HfButtonValueChanged, true);
            app.HfButton.Text = 'Hf';
            app.ZrButton = uibutton(app.GridLayout, 'state');
            app.ZrButton.ValueChangedFcn = createCallbackFcn(app, @ZrButtonValueChanged, true);
            app.ZrButton.Text = 'Zr';
            app.TiButton = uibutton(app.GridLayout, 'state');
            app.TiButton.ValueChangedFcn = createCallbackFcn(app, @TiButtonValueChanged, true);
            app.TiButton.Text = 'Ti';
            app.LaButton = uibutton(app.GridLayout, 'state');
            app.LaButton.ValueChangedFcn = createCallbackFcn(app, @LaButtonValueChanged, true);
            app.LaButton.Text = 'La';
            app.YButton = uibutton(app.GridLayout, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y';
            app.ScButton = uibutton(app.GridLayout, 'state');
            app.ScButton.ValueChangedFcn = createCallbackFcn(app, @ScButtonValueChanged, true);
            app.ScButton.Text = 'Sc';
            app.BaButton = uibutton(app.GridLayout, 'state');
            app.BaButton.ValueChangedFcn = createCallbackFcn(app, @BaButtonValueChanged, true);
            app.BaButton.Text = 'Ba';
            app.SrButton = uibutton(app.GridLayout, 'state');
            app.SrButton.ValueChangedFcn = createCallbackFcn(app, @SrButtonValueChanged, true);
            app.SrButton.Text = 'Sr';
            app.CaButton = uibutton(app.GridLayout, 'state');
            app.CaButton.ValueChangedFcn = createCallbackFcn(app, @CaButtonValueChanged, true);
            app.CaButton.Text = 'Ca';
            app.MgButton = uibutton(app.GridLayout, 'state');
            app.MgButton.ValueChangedFcn = createCallbackFcn(app, @MgButtonValueChanged, true);
            app.MgButton.Text = 'Mg';
            app.BeButton = uibutton(app.GridLayout, 'state');
            app.BeButton.ValueChangedFcn = createCallbackFcn(app, @BeButtonValueChanged, true);
            app.BeButton.Text = 'Be';
            app.CsButton = uibutton(app.GridLayout, 'state');
            app.CsButton.ValueChangedFcn = createCallbackFcn(app, @CsButtonValueChanged, true);
            app.CsButton.Text = 'Cs';
            app.RbButton = uibutton(app.GridLayout, 'state');
            app.RbButton.ValueChangedFcn = createCallbackFcn(app, @RbButtonValueChanged, true);
            app.RbButton.Text = 'Rb';
            app.KButton = uibutton(app.GridLayout, 'state');
            app.KButton.ValueChangedFcn = createCallbackFcn(app, @KButtonValueChanged, true);
            app.KButton.Text = 'K';
            app.NaButton = uibutton(app.GridLayout, 'state');
            app.NaButton.ValueChangedFcn = createCallbackFcn(app, @NaButtonValueChanged, true);
            app.NaButton.Text = 'Na';
            app.LiButton = uibutton(app.GridLayout, 'state');
            app.LiButton.ValueChangedFcn = createCallbackFcn(app, @LiButtonValueChanged, true);
            app.LiButton.Text = 'Li';
            app.RhButton = uibutton(app.GridLayout, 'state');
            app.RhButton.ValueChangedFcn = createCallbackFcn(app, @RhButtonValueChanged, true);
            app.RhButton.Text = 'Rh';
            app.PdButton = uibutton(app.GridLayout, 'state');
            app.PdButton.ValueChangedFcn = createCallbackFcn(app, @PdButtonValueChanged, true);
            app.PdButton.Text = 'Pd';
            app.AgButton = uibutton(app.GridLayout, 'state');
            app.AgButton.ValueChangedFcn = createCallbackFcn(app, @AgButtonValueChanged, true);
            app.AgButton.Text = 'Ag';
            app.CdButton = uibutton(app.GridLayout, 'state');
            app.CdButton.ValueChangedFcn = createCallbackFcn(app, @CdButtonValueChanged, true);
            app.CdButton.Text = 'Cd';
            app.InButton = uibutton(app.GridLayout, 'state');
            app.InButton.ValueChangedFcn = createCallbackFcn(app, @InButtonValueChanged, true);
            app.InButton.Text = 'In';
            app.SnButton = uibutton(app.GridLayout, 'state');
            app.SnButton.ValueChangedFcn = createCallbackFcn(app, @SnButtonValueChanged, true);
            app.SnButton.Text = 'Sn';
            app.SbButton = uibutton(app.GridLayout, 'state');
            app.SbButton.ValueChangedFcn = createCallbackFcn(app, @SbButtonValueChanged, true);
            app.SbButton.Text = 'Sb';
            app.IrButton = uibutton(app.GridLayout, 'state');
            app.IrButton.ValueChangedFcn = createCallbackFcn(app, @IrButtonValueChanged, true);
            app.IrButton.Text = 'Ir';
            app.PtButton = uibutton(app.GridLayout, 'state');
            app.PtButton.ValueChangedFcn = createCallbackFcn(app, @PtButtonValueChanged, true);
            app.PtButton.Text = 'Pt';
            app.AuButton = uibutton(app.GridLayout, 'state');
            app.AuButton.ValueChangedFcn = createCallbackFcn(app, @AuButtonValueChanged, true);
            app.AuButton.Text = 'Au';
            app.HgButton = uibutton(app.GridLayout, 'state');
            app.HgButton.ValueChangedFcn = createCallbackFcn(app, @HgButtonValueChanged, true);
            app.HgButton.Text = 'Hg';
            app.TlButton = uibutton(app.GridLayout, 'state');
            app.TlButton.ValueChangedFcn = createCallbackFcn(app, @TlButtonValueChanged, true);
            app.TlButton.Text = 'Tl';
            app.PbButton = uibutton(app.GridLayout, 'state');
            app.PbButton.ValueChangedFcn = createCallbackFcn(app, @PbButtonValueChanged, true);
            app.PbButton.Text = 'Pb';
            app.BiButton = uibutton(app.GridLayout, 'state');
            app.BiButton.ValueChangedFcn = createCallbackFcn(app, @BiButtonValueChanged, true);
            app.BiButton.Text = 'Bi';
            app.AlButton = uibutton(app.GridLayout, 'state');
            app.AlButton.ValueChangedFcn = createCallbackFcn(app, @AlButtonValueChanged, true);
            app.AlButton.Text = 'Al';
            app.SiButton = uibutton(app.GridLayout, 'state');
            app.SiButton.ValueChangedFcn = createCallbackFcn(app, @SiButtonValueChanged, true);
            app.SiButton.Text = 'Si';
            app.PButton = uibutton(app.GridLayout, 'state');
            app.PButton.ValueChangedFcn = createCallbackFcn(app, @PButtonValueChanged, true);
            app.PButton.Text = 'P';
            app.BButton = uibutton(app.GridLayout, 'state');
            app.BButton.ValueChangedFcn = createCallbackFcn(app, @BButtonValueChanged, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.GridLayout, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.NButton = uibutton(app.GridLayout, 'state');
            app.NButton.ValueChangedFcn = createCallbackFcn(app, @NButtonValueChanged, true);
            app.NButton.Text = 'N';
            app.Import = uipanel(app.GridLayout);
            app.ImportButton = uibutton(app.Import, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportAlloyLabel = uilabel(app.Import);
            app.ImportAlloyLabel.Text = 'Import Alloy';
            app.AlloyImportEditField = uieditfield(app.Import, 'text');
            app.AlloyImportEditField.Value = 'Import';
            app.AmountDropDown_2 = uidropdown(app.Import);
            app.AmountDropDown_2.Items = {'Composition scale', 'molar ratio', 'weight percent', 'atomic percent'};
            app.AmountDropDown_2.Value = 'Composition scale';
            app.Display = uitabgroup(app.GridLayout3);
            app.PropertiesParametersTab = uitab(app.Display);
            app.PropertiesParametersTab.Title = 'Properties & Parameters';
            app.PropertiesParametersTable = uitable(app.PropertiesParametersTab);
            app.PropertiesParametersTable.ColumnName = {'System'; 'T (K)'; 'Tm (K)'; 'At. Weight (g·mol⁻¹)'; 'ρ (g·cm⁻³)'; 'Cp (J·mol⁻¹·K⁻¹)'; 'Thc (W·m⁻¹·K⁻¹)'; 'r (pm)'; 'χᴾ (-)'; 'χᴬ (-)'; 'VEC (-)'; 'e/a (-)'; 'δr (%)'; 'δχᴾ  (%)'; 'Δχᴾ  (-)'; 'δχᴬ (-)'; 'Δχᴬ (-)'; 'ΔVEC (-)'; 'ΔHᵐ (kJ·mol⁻¹)'; 'ΔSᵐ (J·mol⁻¹·K⁻¹)'; 'ΔGᵐ (kJ·mol⁻¹)'; 'ΔHᶦᶜ (kJ·mol⁻¹)'; 'Sˣˢ (bcc) (J·mol⁻¹·K⁻¹)'; 'Sˣˢ (fcc) (J·mol⁻¹·K⁻¹)'; 'ɣ (-)'; 'Ω (-)'; 'Λ (J·mol⁻¹·K⁻¹)'; 'φ (bcc) (-)'; 'φ (fcc) (-)'; 'η (-)'; 'Δκ (-)'; 'PSFE (%)'};
            app.PropertiesParametersTable.RowName = {};
            app.PropertiesParametersTable.ColumnSortable = true;
            app.CriteriaTab = uitab(app.Display);
            app.CriteriaTab.Title = 'Criteria';
            app.CriteriaTable = uitable(app.CriteriaTab);
            app.CriteriaTable.ColumnName = {'System'; 'T (K)'; 'MC1 ΔHᵐ-δr'; 'MC2 Ω-δr'; 'MC3 δr-ΔHᵐ'; 'MC4 γ'; 'MC5 δχᴬ-δr'; 'MC6 Λ'; 'MC7 T/Tm-δr-ΔHᵐ'; 'MC8 φ'; 'MC9 η-ΔHᶦᵐ'; 'MC10 Δk'; 'LSS1 VEC'; 'LSS2 VEC-e/a'; 'LSS3 T/Tm-VEC'; 'LSS4 φ-VEC'; 'LSS5 T-VEC-r-Δχᴬ-ΔVEC'; 'ImF1 Δχᴾ'; 'ImF2 PSFE-VEC'; 'ImF3 δχᴬ-δr'; 'FMP1 VEC'};
            app.CriteriaTable.RowName = {};
            app.AtomicFractionTab = uitab(app.Display);
            app.AtomicFractionTab.Title = 'Atomic Fraction';
            app.AtomicFractionTable = uitable(app.AtomicFractionTab);
            app.AtomicFractionTable.ColumnName = {'System'; 'Components'};
            app.AtomicFractionTable.RowName = {};
            app.WeightFractionTab = uitab(app.Display);
            app.WeightFractionTab.Title = 'Weight Fraction';
            app.WeightFractionTable = uitable(app.WeightFractionTab);
            app.WeightFractionTable.ColumnName = {'System'; 'Components'};
            app.WeightFractionTable.RowName = {};
            app.PropElementsTab = uitab(app.Display);
            app.PropElementsTab.Title = 'Element Properties';
            app.PropElementsTable = uitable(app.PropElementsTab);
            app.PropElementsTable.ColumnName = {'Properties'; 'Components'};
            app.PropElementsTable.RowName = {};
            app.FiltersPanel = uipanel(app.GridLayout3);
            app.FiltersPanel.Title = 'Filters Panel';
            app.FiltersPanel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.FiltersPanel);
            app.xButton = uibutton(app.GridLayout2, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.r1 = uieditfield(app.GridLayout2, 'numeric');
            app.r2 = uieditfield(app.GridLayout2, 'numeric');
            app.R2 = uieditfield(app.GridLayout2, 'numeric');
            app.R2.Value = 100;
            app.r3 = uieditfield(app.GridLayout2, 'numeric');
            app.R3 = uieditfield(app.GridLayout2, 'numeric');
            app.R3.Value = 1000;
            app.r4 = uieditfield(app.GridLayout2, 'numeric');
            app.R4 = uieditfield(app.GridLayout2, 'numeric');
            app.R4.Value = 100;
            app.r5 = uieditfield(app.GridLayout2, 'numeric');
            app.R5 = uieditfield(app.GridLayout2, 'numeric');
            app.R5.Value = 100;
            app.r6 = uieditfield(app.GridLayout2, 'numeric');
            app.R6 = uieditfield(app.GridLayout2, 'numeric');
            app.R6.Value = 100;
            app.r7 = uieditfield(app.GridLayout2, 'numeric');
            app.R7 = uieditfield(app.GridLayout2, 'numeric');
            app.R7.Value = 100;
            app.r8 = uieditfield(app.GridLayout2, 'numeric');
            app.R8 = uieditfield(app.GridLayout2, 'numeric');
            app.R8.Value = 100;
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '≤';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '≤';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '≤';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '≤';
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '≤';
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '≤';
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = '≤';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '≤';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '≤';
            app.Label_10 = uilabel(app.GridLayout2);
            app.Label_10.Text = '≤';
            app.Label_11 = uilabel(app.GridLayout2);
            app.Label_11.Text = '≤';
            app.Label_12 = uilabel(app.GridLayout2);
            app.Label_12.Text = '≤';
            app.Label_13 = uilabel(app.GridLayout2);
            app.Label_13.Text = '≤';
            app.Label_14 = uilabel(app.GridLayout2);
            app.Label_14.Text = '≤';
            app.Label_15 = uilabel(app.GridLayout2);
            app.Label_15.Text = '≤';
            app.Label_16 = uilabel(app.GridLayout2);
            app.Label_16.Text = '≤';
            app.r11 = uieditfield(app.GridLayout2, 'numeric');
            app.R11 = uieditfield(app.GridLayout2, 'numeric');
            app.R11.Value = 100;
            app.Label_17 = uilabel(app.GridLayout2);
            app.Label_17.Text = '≤';
            app.Label_18 = uilabel(app.GridLayout2);
            app.Label_18.Text = '≤';
            app.r12 = uieditfield(app.GridLayout2, 'numeric');
            app.R12 = uieditfield(app.GridLayout2, 'numeric');
            app.R12.Value = 100;
            app.Label_19 = uilabel(app.GridLayout2);
            app.Label_19.Text = '≤';
            app.Label_20 = uilabel(app.GridLayout2);
            app.Label_20.Text = '≤';
            app.r14 = uieditfield(app.GridLayout2, 'numeric');
            app.r14.Value = -1000;
            app.R14 = uieditfield(app.GridLayout2, 'numeric');
            app.R14.Value = 1000;
            app.Label_21 = uilabel(app.GridLayout2);
            app.Label_21.Text = '≤';
            app.Label_22 = uilabel(app.GridLayout2);
            app.Label_22.Text = '≤';
            app.GammaLabel = uilabel(app.GridLayout2);
            app.GammaLabel.Text = 'Gamma [γ] (-)';
            app.HkJmolLabel = uilabel(app.GridLayout2);
            app.HkJmolLabel.Text = 'ΔHᵐ (kJ mol⁻¹)';
            app.GkJmolLabel = uilabel(app.GridLayout2);
            app.GkJmolLabel.Text = ' ΔGᵐ (kJ mol⁻¹)';
            app.VECLabel_2 = uilabel(app.GridLayout2);
            app.VECLabel_2.Text = 'VEC (-)';
            app.Label_59 = uilabel(app.GridLayout2);
            app.Label_59.Text = 'Δχᴬ (-)';
            app.rLabel = uilabel(app.GridLayout2);
            app.rLabel.Text = 'δr (%)';
            app.OmegaLabel = uilabel(app.GridLayout2);
            app.OmegaLabel.Text = 'Omega [Ω] (-)';
            app.VECLabel = uilabel(app.GridLayout2);
            app.VECLabel.Text = 'ΔVEC (-)';
            app.DensitygcmLabel = uilabel(app.GridLayout2);
            app.DensitygcmLabel.Text = 'Density [ρ] (g cm⁻³)';
            app.MeltingpointTmKLabel = uilabel(app.GridLayout2);
            app.MeltingpointTmKLabel.Text = 'Melting point [Tm] (K)';
            app.R1 = uieditfield(app.GridLayout2, 'numeric');
            app.R1.Value = 5000;
            app.Label_62 = uilabel(app.GridLayout2);
            app.Label_62.Text = 'χᴾ (-)';
            app.Label_57 = uilabel(app.GridLayout2);
            app.Label_57.Text = 'χᴬ (-)';
            app.SJmolKLabel = uilabel(app.GridLayout2);
            app.SJmolKLabel.Text = 'ΔSᵐ (J mol⁻¹ K⁻¹)';
            app.r15 = uieditfield(app.GridLayout2, 'numeric');
            app.r15.Value = -1000;
            app.Label_23 = uilabel(app.GridLayout2);
            app.Label_23.Text = '≤';
            app.Label_24 = uilabel(app.GridLayout2);
            app.Label_24.Text = '≤';
            app.R15 = uieditfield(app.GridLayout2, 'numeric');
            app.R15.Value = 1000;
            app.r16 = uieditfield(app.GridLayout2, 'numeric');
            app.r16.Value = -1000;
            app.Label_25 = uilabel(app.GridLayout2);
            app.Label_25.Text = '≤';
            app.Label_26 = uilabel(app.GridLayout2);
            app.Label_26.Text = '≤';
            app.R16 = uieditfield(app.GridLayout2, 'numeric');
            app.R16.Value = 1000;
            app.r17 = uieditfield(app.GridLayout2, 'numeric');
            app.r17.Value = -1000;
            app.Label_27 = uilabel(app.GridLayout2);
            app.Label_27.Text = '≤';
            app.Label_28 = uilabel(app.GridLayout2);
            app.Label_28.Text = '≤';
            app.R17 = uieditfield(app.GridLayout2, 'numeric');
            app.R17.Value = 1000;
            app.r18 = uieditfield(app.GridLayout2, 'numeric');
            app.r18.Value = -1000;
            app.Label_29 = uilabel(app.GridLayout2);
            app.Label_29.Text = '≤';
            app.Label_30 = uilabel(app.GridLayout2);
            app.Label_30.Text = '≤';
            app.R18 = uieditfield(app.GridLayout2, 'numeric');
            app.R18.Value = 1000;
            app.MC1CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC1CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC1CriterionCheckBoxValueChanged, true);
            app.MC1CriterionCheckBox.Tooltip = {'Zhang, Y., Zhou, Y. J., Lin, J. P., Chen, G. L., & Liaw, P. K. (2008) Advanced Engineering Materials'; 'DOI: 10.1002/adem.200700240'; 'SS → 0.5 < δr < 6.5 & -17.5 < ΔHm < 5'; 'IM/BMG → δr > 6.5 or -17.5 > ΔHm or ΔHm > 5'; ''};
            app.MC1CriterionCheckBox.Text = 'MC1 Criterion';
            app.MC2CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC2CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC2CriterionCheckBoxValueChanged, true);
            app.MC2CriterionCheckBox.Tooltip = {'Yang, X., & Zhang, Y. (2012)'; 'Materials Chemistry and Physics'; 'DOI: 10.1016/j.matchemphys.2011.11.021'; 'SS → 1.1 ≤ Ω & δr ≤ 6.6'; 'IM → Ω < 1.1 or δr > 6.6'};
            app.MC2CriterionCheckBox.Text = 'MC2 Criterion';
            app.MC3CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC3CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC3CriterionCheckBoxValueChanged, true);
            app.MC3CriterionCheckBox.Tooltip = {'Guo, S., Hu, Q., Ng, C., & Liu, C. T. (2013)'; 'Intermetallics'; 'DOI: 10.1016/j.intermet.2013.05.002'; 'SS → -11.6 < ΔHm < 3.2 & δr ≤ 6.6'; 'BMG → ΔHm < -12 & 6.4 < δr'};
            app.MC3CriterionCheckBox.Text = 'MC3 Criterion';
            app.MC4CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC4CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC4CriterionCheckBoxValueChanged, true);
            app.MC4CriterionCheckBox.Tooltip = {'Wang, Z., Huang, Y., Yang, Y., Wang, J., & Liu, C. T. (2015)'; 'Scripta Materialia'; 'DOI: 10.1016/j.scriptamat.2014.09.010'; 'SS → γ ≤ 1.175'; 'IM/BMG → 1.175 < γ'};
            app.MC4CriterionCheckBox.Text = 'MC4 Criterion';
            app.MC5CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC5CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC5CriterionCheckBoxValueChanged, true);
            app.MC5CriterionCheckBox.Tooltip = {'Poletti, M. G., & Battezzati, L. J. A. M. (2014)'; 'Acta Materialia'; 'DOI: 10.1016/j.actamat.2014.04.033'; 'SS →1 < δr < 6 & 3 < δχA < 6'; 'IM → δr > 6 or δχA < 3 or δχA > 6'};
            app.MC5CriterionCheckBox.Text = 'MC5 Criterion';
            app.MC6CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC6CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC6CriterionCheckBoxValueChanged, true);
            app.MC6CriterionCheckBox.Tooltip = {'Singh, A. K., Kumar, N., Dwivedi, A., & Subramaniam, A. (2014)'; 'Intermetallics'; 'DOI: 10.1016/j.intermet.2014.04.019'; 'SPSS → 0.96 ≤ Λ'; 'MPSS → 0.24 < Λ < 0.96'; 'IM → Λ ≤ 0.24'};
            app.MC6CriterionCheckBox.Text = 'MC6 Criterion';
            app.MC1DropDown = uidropdown(app.GridLayout2);
            app.MC1DropDown.Items = {'Select', 'SS', 'IM/BMG'};
            app.MC1DropDown.ValueChangedFcn = createCallbackFcn(app, @MC1DropDownValueChanged, true);
            app.MC1DropDown.Enable = 'off';
            app.MC1DropDown.Value = 'Select';
            app.MC7CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC7CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC7CriterionCheckBoxValueChanged, true);
            app.MC7CriterionCheckBox.Tooltip = {'Wang, Z., Guo, S., & Liu, C. T. (2014)'; 'JOM'; 'DOI: 10.1007/s11837-014-0953-8'; 'If: 0.9 < Tin/Tm'; 'SS → -15 ≤ ΔHm ≤ 5 & δr ≤ 6.6'; 'IM → ΔHm < -15 or 6.6 < δr '; 'If: 0.5 ≤ Tin/Tm < 0.9'; 'SS → -7.5 ≤ ΔHm & δr  ≤ 3.3'; 'IM → -7.5 ≤ ΔHm or 3.3 < δr'};
            app.MC7CriterionCheckBox.Text = 'MC7 Criterion';
            app.MC9CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC9CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC9CriterionCheckBoxValueChanged, true);
            app.MC9CriterionCheckBox.Tooltip = {'Troparevsky, M. C., Morris, J. R., Kent, P. R., Lupini, A. R., & Stocks, G. M. (2015)'; 'Physical Review X'; 'DOI: 10.1103/PhysRevX.5.011041'; 'SPSS → η >1 & min{ΔH_ij^ic} < 3.7 '; 'IM → η < 1'};
            app.MC9CriterionCheckBox.Text = 'MC9 Criterion';
            app.MC8CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC8CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC8CriterionCheckBoxValueChanged, true);
            app.MC8CriterionCheckBox.Tooltip = {'Ye, Y. F., Wang, Q., Lu, J., Liu, C. T., & Yang, Y. (2015)'; 'Scripta Materialia'; 'DOI: 10.1016/j.scriptamat.2015.03.023'; 'SPSS → 20 ≤ ⏀'; 'MPSS/IM → ⏀ < 20'};
            app.MC8CriterionCheckBox.Text = 'MC8 Criterion';
            app.MC10CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.MC10CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @MC10CriterionCheckBoxValueChanged, true);
            app.MC10CriterionCheckBox.Tooltip = {'Senkov, O. N., & Miracle, D. B. (2016)'; 'Journal of Alloys and Compounds'; 'DOI: 10.1016/j.jallcom.2015.10.279'; 'SS → 0 ≤ Δκ'; 'IM → Δκ < 0'};
            app.MC10CriterionCheckBox.Text = 'MC10 Criterion';
            app.LSS1CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.LSS1CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LSS1CriterionCheckBoxValueChanged, true);
            app.LSS1CriterionCheckBox.Tooltip = {'Guo, S., Ng, C., Lu, J., & Liu, C. T. (2011)'; 'Journal of applied physics'; 'DOI: 10.1063/1.3587228'; 'BCC → VEC  < 6.87'; 'BCC + FCC → 6.87 < VEC  < 8'; 'FCC → 8 ≤ VEC '};
            app.LSS1CriterionCheckBox.Text = 'LSS1 Criterion';
            app.LSS2CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.LSS2CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LSS2CriterionCheckBoxValueChanged, true);
            app.LSS2CriterionCheckBox.Tooltip = {'Poletti, M. G., & Battezzati, L. J. A. M. (2014)'; 'Acta materialia'; 'DOI: 10.1016/j.actamat.2014.04.033'; 'BCC → VEC  < 7.5 & 1.8 < e/a < 2.3'; 'FCC → VEC  > 7.5 & 1.6 < e/a < 1.8'};
            app.LSS2CriterionCheckBox.Text = 'LSS2 Criterion';
            app.LSS3CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.LSS3CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LSS3CriterionCheckBoxValueChanged, true);
            app.LSS3CriterionCheckBox.Tooltip = {'Wang, Z., Guo, S., & Liu, C. T. (2014)'; 'JOM'; 'DOI: 10.1007/s11837-014-0953-8'; 'If: 0.9 < Tin/Tm'; 'FCC → 7.84 < VEC '; 'BCC + FCC → 6.87 ≤ VEC  ≤ 7.84'; 'BCC → VEC  < 6.87'; 'If: 0.5 < Tin/Tm < 0.9'; 'FCC → 7.8 < VEC '; 'BCC + FCC → 6 ≤ VEC ≤ 7.8'; 'BCC → VEC  < 6'};
            app.LSS3CriterionCheckBox.Text = 'LSS3 Criterion';
            app.LSS4CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.LSS4CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LSS4CriterionCheckBoxValueChanged, true);
            app.LSS4CriterionCheckBox.Tooltip = {'Ye, Y. F., Wang, Q., Lu, J., Liu, C. T., & Yang, Y. (2016)'; 'Materials Today'; 'DOI: 10.1016/j.mattod.2015.11.026'; 'If: 20 ≤ ⏀'; 'FCC → 7.5 < VEC  < 9.5'; 'BCC → 4.3 < VEC  < 5.7'; 'HCP → 2.6 < VEC  < 3'};
            app.LSS4CriterionCheckBox.Text = 'LSS4 Criterion';
            app.LSS5CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.LSS5CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LSS5CriterionCheckBoxValueChanged, true);
            app.LSS5CriterionCheckBox.Tooltip = {'Zeng, Y., Man, M., Bai, K., & Zhang, Y. W. (2021)'; 'Materials & Design'; 'DOI: 10.1016/j.matdes.2021.109532'; 'BCC → 1330 < Tin < 1690 & 6.45 < VEC < 7.55 & 1.28 < ¬r < 1.3 & 0.071 < ∆χA < 0.096 & 1.46 < ∆VEC < 2.1'; 'FCC → 1080 < Teq < 1660 & 7.8 < VEC < 8.96 & 1.26 < r < 1.28 & 0.066 < ∆χA < 0.105 &'; '1.5 < ∆VEC < 2.26'};
            app.LSS5CriterionCheckBox.Text = 'LSS5 Criterion';
            app.ImF1CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.ImF1CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImF1CriterionCheckBoxValueChanged, true);
            app.ImF1CriterionCheckBox.Tooltip = {'Dong, Y., Lu, Y., Jiang, L., Wang, T., & Li, T. (2014)'; 'Intermetallics'; 'DOI: 10.1016/j.intermet.2014.04.001'; 'TCP Phase → 0.133 < ∆χP'; 'TCP Free → ∆χP < 0.117'};
            app.ImF1CriterionCheckBox.Text = 'ImF1 Criterion';
            app.ImF2CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.ImF2CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImF2CriterionCheckBoxValueChanged, true);
            app.ImF2CriterionCheckBox.Tooltip = {'Tsai, M. H., Chang, K. C., Li, J. H., Tsai, R. C., & Cheng, A. H. (2016)'; 'Materials Research Letters'; 'DOI: 10.1080/21663831.2015.1121168'; 'Sigma Phase → 6.88 ≤ VEC  ≤ 7.84 & 42.5 < PSFE'; 'Sigma Free → PSFE < 22.5 or VEC  < 6.88 or VEC  > 7.84'};
            app.ImF2CriterionCheckBox.Text = 'ImF2 Criterion';
            app.ImF3CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.ImF3CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImF3CriterionCheckBoxValueChanged, true);
            app.ImF3CriterionCheckBox.Tooltip = {'Yurchenko, N., Stepanov, N., & Salishchev, G. (2017)'; 'Materials Science and Technology'; 'DOI: 10.1080/02670836.2016.1153277'; 'Laves Phase → 7 < δχA & 5 < δr'; 'Laves Free → δχA < 7 or δr > 5'};
            app.ImF3CriterionCheckBox.Text = 'ImF3 Criterion';
            app.FMP1CriterionCheckBox = uicheckbox(app.GridLayout2);
            app.FMP1CriterionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FMP1CriterionCheckBoxValueChanged, true);
            app.FMP1CriterionCheckBox.Tooltip = {'Sheikh, S., Shafeie, S., Hu, Q., Ahlström, J., Persson, C., Veselý, J., ... & Guo, S. (2016)'; 'Journal of applied physics'; 'DOI: 10.1063/1.4966659'; 'Ductile → VEC  < 4.4'; 'Brittle → 4.6 < VEC '};
            app.FMP1CriterionCheckBox.Text = 'FMP1 Criterion';
            app.MC2DropDown = uidropdown(app.GridLayout2);
            app.MC2DropDown.Items = {'Select', 'SS', 'IM'};
            app.MC2DropDown.ValueChangedFcn = createCallbackFcn(app, @MC2DropDownValueChanged, true);
            app.MC2DropDown.Enable = 'off';
            app.MC2DropDown.Value = 'Select';
            app.MC3DropDown = uidropdown(app.GridLayout2);
            app.MC3DropDown.Items = {'Select', 'SS', 'BMG'};
            app.MC3DropDown.ValueChangedFcn = createCallbackFcn(app, @MC3DropDownValueChanged, true);
            app.MC3DropDown.Enable = 'off';
            app.MC3DropDown.Value = 'Select';
            app.MC4DropDown = uidropdown(app.GridLayout2);
            app.MC4DropDown.Items = {'Select', 'SS', 'IM/BMG'};
            app.MC4DropDown.ValueChangedFcn = createCallbackFcn(app, @MC4DropDownValueChanged, true);
            app.MC4DropDown.Enable = 'off';
            app.MC4DropDown.Value = 'Select';
            app.MC5DropDown = uidropdown(app.GridLayout2);
            app.MC5DropDown.Items = {'Select', 'SS', 'IM'};
            app.MC5DropDown.ValueChangedFcn = createCallbackFcn(app, @MC5DropDownValueChanged, true);
            app.MC5DropDown.Enable = 'off';
            app.MC5DropDown.Value = 'Select';
            app.MC6DropDown = uidropdown(app.GridLayout2);
            app.MC6DropDown.Items = {'Select', 'SPSS', 'MPSS', 'IM'};
            app.MC6DropDown.ValueChangedFcn = createCallbackFcn(app, @MC6DropDownValueChanged, true);
            app.MC6DropDown.Enable = 'off';
            app.MC6DropDown.Value = 'Select';
            app.MC7DropDown = uidropdown(app.GridLayout2);
            app.MC7DropDown.Items = {'Select', 'SS(0.9<T/Tm)', 'IM(0.9<T/Tm)', 'SS(0.5<T/Tm<0.9)', 'IM(0.5<T/Tm<0.9)'};
            app.MC7DropDown.ValueChangedFcn = createCallbackFcn(app, @MC7DropDownValueChanged, true);
            app.MC7DropDown.Enable = 'off';
            app.MC7DropDown.Value = 'Select';
            app.MC9DropDown = uidropdown(app.GridLayout2);
            app.MC9DropDown.Items = {'Select', 'SPSS', 'IM'};
            app.MC9DropDown.ValueChangedFcn = createCallbackFcn(app, @MC9DropDownValueChanged, true);
            app.MC9DropDown.Enable = 'off';
            app.MC9DropDown.Value = 'Select';
            app.MC8DropDown = uidropdown(app.GridLayout2);
            app.MC8DropDown.Items = {'Select', 'SPSS', 'MPSS/IM'};
            app.MC8DropDown.ValueChangedFcn = createCallbackFcn(app, @MC8DropDownValueChanged, true);
            app.MC8DropDown.Enable = 'off';
            app.MC8DropDown.Value = 'Select';
            app.MC10DropDown = uidropdown(app.GridLayout2);
            app.MC10DropDown.Items = {'Select', 'SS', 'IM'};
            app.MC10DropDown.ValueChangedFcn = createCallbackFcn(app, @MC10DropDownValueChanged, true);
            app.MC10DropDown.Enable = 'off';
            app.MC10DropDown.Value = 'Select';
            app.LSS1DropDown = uidropdown(app.GridLayout2);
            app.LSS1DropDown.Items = {'Select', 'BCC', 'BCC+FCC', 'FCC'};
            app.LSS1DropDown.ValueChangedFcn = createCallbackFcn(app, @LSS1DropDownValueChanged, true);
            app.LSS1DropDown.Enable = 'off';
            app.LSS1DropDown.Value = 'Select';
            app.LSS2DropDown = uidropdown(app.GridLayout2);
            app.LSS2DropDown.Items = {'Select', 'BCC', 'FCC'};
            app.LSS2DropDown.ValueChangedFcn = createCallbackFcn(app, @LSS2DropDownValueChanged, true);
            app.LSS2DropDown.Enable = 'off';
            app.LSS2DropDown.Value = 'Select';
            app.LSS3DropDown = uidropdown(app.GridLayout2);
            app.LSS3DropDown.Items = {'Select', 'FCC(0.9<T/Tm)', 'BCC+FCC(0.9<T/Tm)', 'BCC(0.9<T/Tm)', 'FCC(0.5<T/Tm<0.9)', 'BCC+FCC(0.5<T/Tm<0.9)', 'BCC(0.5<T/Tm<0.9)'};
            app.LSS3DropDown.ValueChangedFcn = createCallbackFcn(app, @LSS3DropDownValueChanged, true);
            app.LSS3DropDown.Enable = 'off';
            app.LSS3DropDown.Value = 'Select';
            app.LSS4DropDown = uidropdown(app.GridLayout2);
            app.LSS4DropDown.Items = {'Select', 'FCC', 'BCC', 'HCP'};
            app.LSS4DropDown.ValueChangedFcn = createCallbackFcn(app, @LSS4DropDownValueChanged, true);
            app.LSS4DropDown.Enable = 'off';
            app.LSS4DropDown.Value = 'Select';
            app.LSS5DropDown = uidropdown(app.GridLayout2);
            app.LSS5DropDown.Items = {'Select', 'BCC', 'FCC'};
            app.LSS5DropDown.ValueChangedFcn = createCallbackFcn(app, @LSS5DropDownValueChanged, true);
            app.LSS5DropDown.Enable = 'off';
            app.LSS5DropDown.Value = 'Select';
            app.ImF1DropDown = uidropdown(app.GridLayout2);
            app.ImF1DropDown.Items = {'Select', 'TCP Phase', 'TCP Free'};
            app.ImF1DropDown.ValueChangedFcn = createCallbackFcn(app, @ImF1DropDownValueChanged, true);
            app.ImF1DropDown.Enable = 'off';
            app.ImF1DropDown.Value = 'Select';
            app.ImF2DropDown = uidropdown(app.GridLayout2);
            app.ImF2DropDown.Items = {'Select', 'Sigma Phase', 'Sigma Free'};
            app.ImF2DropDown.ValueChangedFcn = createCallbackFcn(app, @ImF2DropDownValueChanged, true);
            app.ImF2DropDown.Enable = 'off';
            app.ImF2DropDown.Value = 'Select';
            app.ImF3DropDown = uidropdown(app.GridLayout2);
            app.ImF3DropDown.Items = {'Select', 'Laves Phase', 'Laves Free'};
            app.ImF3DropDown.ValueChangedFcn = createCallbackFcn(app, @ImF3DropDownValueChanged, true);
            app.ImF3DropDown.Enable = 'off';
            app.ImF3DropDown.Value = 'Select';
            app.FMP1DropDown = uidropdown(app.GridLayout2);
            app.FMP1DropDown.Items = {'Select', 'Ductile', 'Brittle'};
            app.FMP1DropDown.ValueChangedFcn = createCallbackFcn(app, @FMP1DropDownValueChanged, true);
            app.FMP1DropDown.Enable = 'off';
            app.FMP1DropDown.Value = 'Select';
            app.LambdakJmolKLabel = uilabel(app.GridLayout2);
            app.LambdakJmolKLabel.Text = 'Lambda [Λ] (kJ mol⁻¹ K⁻¹)';
            app.PhiLabel = uilabel(app.GridLayout2);
            app.PhiLabel.Text = 'Phi [φ] (-)';
            app.kLabel = uilabel(app.GridLayout2);
            app.kLabel.Text = 'Δk (-)';
            app.PSFEatLabel = uilabel(app.GridLayout2);
            app.PSFEatLabel.Text = 'PSFE (at. %)';
            app.Label_31 = uilabel(app.GridLayout2);
            app.Label_31.Text = '≤';
            app.Label_32 = uilabel(app.GridLayout2);
            app.Label_32.Text = '≤';
            app.Label_33 = uilabel(app.GridLayout2);
            app.Label_33.Text = '≤';
            app.Label_34 = uilabel(app.GridLayout2);
            app.Label_34.Text = '≤';
            app.Label_35 = uilabel(app.GridLayout2);
            app.Label_35.Text = '≤';
            app.Label_36 = uilabel(app.GridLayout2);
            app.Label_36.Text = '≤';
            app.Label_37 = uilabel(app.GridLayout2);
            app.Label_37.Text = '≤';
            app.Label_38 = uilabel(app.GridLayout2);
            app.Label_38.Text = '≤';
            app.R19 = uieditfield(app.GridLayout2, 'numeric');
            app.R19.Value = 1000;
            app.R20 = uieditfield(app.GridLayout2, 'numeric');
            app.R20.Value = 1000;
            app.R21 = uieditfield(app.GridLayout2, 'numeric');
            app.R21.Value = 1000;
            app.R23 = uieditfield(app.GridLayout2, 'numeric');
            app.R23.Value = 1000;
            app.r19 = uieditfield(app.GridLayout2, 'numeric');
            app.r19.Value = -1000;
            app.r20 = uieditfield(app.GridLayout2, 'numeric');
            app.r20.Value = -1000;
            app.r21 = uieditfield(app.GridLayout2, 'numeric');
            app.r21.Value = -1000;
            app.r23 = uieditfield(app.GridLayout2, 'numeric');
            app.r23.Value = -1000;
            app.r22 = uieditfield(app.GridLayout2, 'numeric');
            app.r22.Value = -1000;
            app.R22 = uieditfield(app.GridLayout2, 'numeric');
            app.R22.Value = 1000;
            app.Label_40 = uilabel(app.GridLayout2);
            app.Label_40.Text = '≤';
            app.Label_41 = uilabel(app.GridLayout2);
            app.Label_41.Text = '≤';
            app.EtaLabel = uilabel(app.GridLayout2);
            app.EtaLabel.Text = 'Eta [η] (-)';
            app.r13 = uieditfield(app.GridLayout2, 'numeric');
            app.R13 = uieditfield(app.GridLayout2, 'numeric');
            app.R13.Value = 100;
            app.Label_42 = uilabel(app.GridLayout2);
            app.Label_42.Text = '≤';
            app.Label_43 = uilabel(app.GridLayout2);
            app.Label_43.Text = '≤';
            app.eaLabel = uilabel(app.GridLayout2);
            app.eaLabel.Text = 'e/a (-)';
            app.rpmLabel = uilabel(app.GridLayout2);
            app.rpmLabel.Text = 'r (pm)';
            app.Label_45 = uilabel(app.GridLayout2);
            app.Label_45.Text = '≤';
            app.Label_46 = uilabel(app.GridLayout2);
            app.Label_46.Text = '≤';
            app.Label_47 = uilabel(app.GridLayout2);
            app.Label_47.Text = '≤';
            app.Label_48 = uilabel(app.GridLayout2);
            app.Label_48.Text = '≤';
            app.HkJmolLabel_2 = uilabel(app.GridLayout2);
            app.HkJmolLabel_2.Text = 'ΔHᶦᶜ (kJ mol⁻¹)';
            app.Label_61 = uilabel(app.GridLayout2);
            app.Label_61.Text = 'δχᴾ (%)';
            app.r9 = uieditfield(app.GridLayout2, 'numeric');
            app.r10 = uieditfield(app.GridLayout2, 'numeric');
            app.R9 = uieditfield(app.GridLayout2, 'numeric');
            app.R9.Value = 100;
            app.R10 = uieditfield(app.GridLayout2, 'numeric');
            app.R10.Value = 100;
            app.Label_49 = uilabel(app.GridLayout2);
            app.Label_49.Text = '≤';
            app.Label_50 = uilabel(app.GridLayout2);
            app.Label_50.Text = '≤';
            app.R24 = uieditfield(app.GridLayout2, 'numeric');
            app.R24.Value = 100;
            app.r24 = uieditfield(app.GridLayout2, 'numeric');
            app.Label_58 = uilabel(app.GridLayout2);
            app.Label_58.Text = 'δχᴬ (%)';
            app.Label_60 = uilabel(app.GridLayout2);
            app.Label_60.Text = 'Δχᴾ (-)';
            app.R25 = uieditfield(app.GridLayout2, 'numeric');
            app.R25.Limits = [0 1];
            app.R25.Value = 0.6;
            app.r25 = uieditfield(app.GridLayout2, 'numeric');
            app.r25.Limits = [0 1];
            app.AtomicfractionLabel_2 = uilabel(app.GridLayout2);
            app.AtomicfractionLabel_2.Text = 'Atomic fraction';
            app.Label_53 = uilabel(app.GridLayout2);
            app.Label_53.Text = '≤';
            app.Label_54 = uilabel(app.GridLayout2);
            app.Label_54.Text = '≤';
            app.NumberofcomponentsLabel_2 = uilabel(app.GridLayout2);
            app.NumberofcomponentsLabel_2.Text = 'Number of components';
            app.r26 = uieditfield(app.GridLayout2, 'numeric');
            app.r26.Limits = [3 10];
            app.r26.Value = 3;
            app.R26 = uieditfield(app.GridLayout2, 'numeric');
            app.R26.Limits = [3 10];
            app.R26.Value = 10;
            app.Label_55 = uilabel(app.GridLayout2);
            app.Label_55.Text = '≤';
            app.Label_56 = uilabel(app.GridLayout2);
            app.Label_56.Text = '≤';
            app.Panel = uipanel(app.GridLayout2);
            % Show the figure after all components are created
            app.HEAPS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEAV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HEAPS)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HEAPS)
        end
    end
end